classdef Controllable_Fgen_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        PulseTab                        matlab.ui.container.Tab
        AmplitudeVEditField_3Label      matlab.ui.control.Label
        AmplitudeVEditField_3           matlab.ui.control.NumericEditField
        StartEditField_2Label           matlab.ui.control.Label
        StartEditField_2                matlab.ui.control.NumericEditField
        DurationEditField_4Label        matlab.ui.control.Label
        DurationEditField_4             matlab.ui.control.NumericEditField
        Typeintor1float33VLabel         matlab.ui.control.Label
        TypeintLabel                    matlab.ui.control.Label
        TypeintLabel_2                  matlab.ui.control.Label
        PulseTrainTab                   matlab.ui.container.Tab
        AmplitudeVEditField_2Label      matlab.ui.control.Label
        AmplitudeVEditField_2           matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        StartEditField_3Label           matlab.ui.control.Label
        StartEditField_3                matlab.ui.control.NumericEditField
        FrequencyHzor1cmEditFieldLabel  matlab.ui.control.Label
        FrequencyHzor1cmEditField       matlab.ui.control.NumericEditField
        PulseWidthEditFieldLabel        matlab.ui.control.Label
        PulseWidthEditField             matlab.ui.control.NumericEditField
        TriangleTab                     matlab.ui.container.Tab
        AmplitudeEditField_3Label       matlab.ui.control.Label
        AmplitudeEditField_3            matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        PeakEditFieldLabel              matlab.ui.control.Label
        PeakEditField                   matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        GaussianTab                     matlab.ui.container.Tab
        CenterEditFieldLabel            matlab.ui.control.Label
        CenterEditField                 matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        DelayEditField_2Label           matlab.ui.control.Label
        DelayEditField_2                matlab.ui.control.NumericEditField
        ParameterLinkingDropDownLabel   matlab.ui.control.Label
        ParameterLinkingDropDown        matlab.ui.control.DropDown
        PulseCheckBox                   matlab.ui.control.CheckBox
        PulseTrainCheckBox              matlab.ui.control.CheckBox
        GaussianCheckBox                matlab.ui.control.CheckBox
        TriangleCheckBox                matlab.ui.control.CheckBox
        SessionSwitchLabel              matlab.ui.control.Label
        SessionSwitch                   matlab.ui.control.Switch
        UpdateButton                    matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        % pulse params 
        pulseAmp =0; % amplitude of pulse V
        pulseDur = 0; % s
        pulseDly = 0; % s
        pulseOn = 0;
        
        %pulse train params
        trainAmp =0;
        trainDur = 0;
        trainDly = 0;
        trainFrq = 0;
        trainWid = 0;
        trainOn = 0;        
        
        %triangle pulse params 
        triAmp = 0;
        triDur = 0;
        triPk = 0;
        triDly = 0;
        triOn = 0;
        triEnd = 0;
        
        %gaussian params
        gausAmp = 0;
        gausCent = 0;
        gausWid = 0;
        gausDly = 0;
        gausOn = 0;      
        
        SerialID
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SessionSwitch
        function SessionSwitchValueChanged(app, event)
            isStartOn  = app.SessionSwitch.Value;
            switch isStartOn 
                case 'On'
                    coms = instrfind;
                    if ~isempty(coms) 
                        fclose(coms);
                    end
                    
                    app.SerialID = serial('COM13','BaudRate',9600);
                    fopen(app.SerialID);
                case 'Clear'
                    fclose(app.SerialID);
            end
        end

        % Value changed function: AmplitudeVEditField_3
        function AmplitudeVEditField_3ValueChanged(app, event)
            app.pulseAmp = app.AmplitudeVEditField_3.Value;
            
        end

        % Value changed function: DurationEditField_4
        function DurationEditField_4ValueChanged(app, event)
            app.pulseDur = app.DurationEditField_4.Value;
            
        end

        % Value changed function: StartEditField_2
        function StartEditField_2ValueChanged(app, event)
            app.pulseDly = app.StartEditField_2.Value;
            
        end

        % Value changed function: PulseCheckBox
        function PulseCheckBoxValueChanged(app, event)
            app.pulseOn = app.PulseCheckBox.Value;
            
        end

        % Value changed function: AmplitudeEditField_3
        function AmplitudeEditField_3ValueChanged(app, event)
            app.triAmp = app.AmplitudeEditField_3.Value;
            
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            app.triEnd = app.EndEditField.Value;
            
        end

        % Value changed function: PeakEditField
        function PeakEditFieldValueChanged(app, event)
            app.triPk = app.PeakEditField.Value;
            
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            app.triDly = app.StartEditField.Value;
            
        end

        % Value changed function: TriangleCheckBox
        function TriangleCheckBoxValueChanged(app, event)
            app.triOn = app.TriangleCheckBox.Value;
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.gausAmp = app.AmplitudeEditField.Value;
           
        end

        % Value changed function: CenterEditField
        function CenterEditFieldValueChanged(app, event)
            app.gausCent = app.CenterEditField.Value;
            
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.gausWid = app.WidthEditField.Value;
            
        end

        % Value changed function: DelayEditField_2
        function DelayEditField_2ValueChanged(app, event)
            app.gausDly = app.DelayEditField_2.Value;
            
        end

        % Value changed function: GaussianCheckBox
        function GaussianCheckBoxValueChanged(app, event)
            app.gausOn = app.GaussianCheckBox.Value;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % sending direction string to teensy
            app.triDur = app.triEnd-app.triDly;
            pulseStr = sprintf('p%g,%g,%g,%g!', app.pulseOn, app.pulseAmp, app.pulseDur, app.pulseDly);           
            trainStr = sprintf('t%g,%g,%g,%g,%g,%g!', app.trainOn, app.trainAmp, app.trainDur, app.trainDly, app.trainFrq, app.trainWid);
            triStr = sprintf('^%g,%g,%g,%g,%g!', app.triOn, app.triAmp, app.triDur, app.triPk, app.triDly);
            gausStr = sprintf('g%g,%g,%g,%g,%g!', app.gausOn, app.gausAmp, app.gausCent, app.gausWid, app.gausDly);
      
            dirStr = sprintf('<%s%s%s%s>\n', pulseStr, trainStr, triStr, gausStr);


                tic;
                for char = dirStr
                    try 
                        fwrite(app.SerialID, char);
                    catch 
                        warning('Something went wrong on transmission of %s: Try turning Session On', char);
                    end
                end
                toc;
                fprintf('%s\n',dirStr);

        end

        % Value changed function: AmplitudeVEditField_2
        function AmplitudeVEditField_2ValueChanged(app, event)
            app.trainAmp = app.AmplitudeVEditField_2.Value;
            
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            app.trainDur = app.DurationEditField.Value;
            
        end

        % Value changed function: StartEditField_3
        function StartEditField_3ValueChanged(app, event)
            app.trainDly = app.StartEditField_3.Value;
            
        end

        % Value changed function: FrequencyHzor1cmEditField
        function FrequencyHzor1cmEditFieldValueChanged(app, event)
            app.trainFrq = app.FrequencyHzor1cmEditField.Value;
            
        end

        % Value changed function: PulseWidthEditField
        function PulseWidthEditFieldValueChanged(app, event)
            app.trainWid = app.PulseWidthEditField.Value;
            
        end

        % Value changed function: PulseTrainCheckBox
        function PulseTrainCheckBoxValueChanged(app, event)
            app.trainOn = app.PulseTrainCheckBox.Value;
            
        end

        % Callback function
        function SlopeEditFieldValueChanged(app, event)
            app.rampSlope = app.SlopeEditField.Value;
            
        end

        % Callback function
        function DelayEditField_7ValueChanged(app, event)
            app.rampDly = app.DelayEditField_7.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {409, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.PulseTab = uitab(app.TabGroup);
            app.PulseTab.Title = 'Pulse';
            app.AmplitudeVEditField_3Label = uilabel(app.PulseTab);
            app.AmplitudeVEditField_3Label.Text = 'Amplitude (V)';
            app.AmplitudeVEditField_3 = uieditfield(app.PulseTab, 'numeric');
            app.AmplitudeVEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVEditField_3ValueChanged, true);
            app.StartEditField_2Label = uilabel(app.PulseTab);
            app.StartEditField_2Label.Text = 'Start';
            app.StartEditField_2 = uieditfield(app.PulseTab, 'numeric');
            app.StartEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartEditField_2ValueChanged, true);
            app.DurationEditField_4Label = uilabel(app.PulseTab);
            app.DurationEditField_4Label.Text = 'Duration';
            app.DurationEditField_4 = uieditfield(app.PulseTab, 'numeric');
            app.DurationEditField_4.ValueChangedFcn = createCallbackFcn(app, @DurationEditField_4ValueChanged, true);
            app.Typeintor1float33VLabel = uilabel(app.PulseTab);
            app.Typeintor1float33VLabel.Text = 'Type: int or .1float, <3.3 V ';
            app.TypeintLabel = uilabel(app.PulseTab);
            app.TypeintLabel.Text = 'Type: int';
            app.TypeintLabel_2 = uilabel(app.PulseTab);
            app.TypeintLabel_2.Text = 'Type: int';
            app.PulseTrainTab = uitab(app.TabGroup);
            app.PulseTrainTab.Title = 'Pulse Train';
            app.AmplitudeVEditField_2Label = uilabel(app.PulseTrainTab);
            app.AmplitudeVEditField_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVEditField_2 = uieditfield(app.PulseTrainTab, 'numeric');
            app.AmplitudeVEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVEditField_2ValueChanged, true);
            app.DurationEditFieldLabel = uilabel(app.PulseTrainTab);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.StartEditField_3Label = uilabel(app.PulseTrainTab);
            app.StartEditField_3Label.Text = 'Start';
            app.StartEditField_3 = uieditfield(app.PulseTrainTab, 'numeric');
            app.StartEditField_3.ValueChangedFcn = createCallbackFcn(app, @StartEditField_3ValueChanged, true);
            app.FrequencyHzor1cmEditFieldLabel = uilabel(app.PulseTrainTab);
            app.FrequencyHzor1cmEditFieldLabel.Text = 'Frequency (Hz or 1/cm)';
            app.FrequencyHzor1cmEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.FrequencyHzor1cmEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzor1cmEditFieldValueChanged, true);
            app.PulseWidthEditFieldLabel = uilabel(app.PulseTrainTab);
            app.PulseWidthEditFieldLabel.Text = 'Pulse Width';
            app.PulseWidthEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.PulseWidthEditField.ValueChangedFcn = createCallbackFcn(app, @PulseWidthEditFieldValueChanged, true);
            app.TriangleTab = uitab(app.TabGroup);
            app.TriangleTab.Title = 'Triangle';
            app.AmplitudeEditField_3Label = uilabel(app.TriangleTab);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField_3 = uieditfield(app.TriangleTab, 'numeric');
            app.AmplitudeEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_3ValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.TriangleTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.TriangleTab, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.PeakEditFieldLabel = uilabel(app.TriangleTab);
            app.PeakEditFieldLabel.Text = 'Peak';
            app.PeakEditField = uieditfield(app.TriangleTab, 'numeric');
            app.PeakEditField.ValueChangedFcn = createCallbackFcn(app, @PeakEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.TriangleTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.TriangleTab, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.GaussianTab = uitab(app.TabGroup);
            app.GaussianTab.Title = 'Gaussian';
            app.CenterEditFieldLabel = uilabel(app.GaussianTab);
            app.CenterEditFieldLabel.Text = 'Center';
            app.CenterEditField = uieditfield(app.GaussianTab, 'numeric');
            app.CenterEditField.ValueChangedFcn = createCallbackFcn(app, @CenterEditFieldValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.GaussianTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.GaussianTab, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.WidthEditFieldLabel = uilabel(app.GaussianTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.GaussianTab, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.DelayEditField_2Label = uilabel(app.GaussianTab);
            app.DelayEditField_2Label.Text = 'Delay';
            app.DelayEditField_2 = uieditfield(app.GaussianTab, 'numeric');
            app.DelayEditField_2.ValueChangedFcn = createCallbackFcn(app, @DelayEditField_2ValueChanged, true);
            app.ParameterLinkingDropDownLabel = uilabel(app.LeftPanel);
            app.ParameterLinkingDropDownLabel.Text = 'Parameter Linking';
            app.ParameterLinkingDropDown = uidropdown(app.LeftPanel);
            app.ParameterLinkingDropDown.Items = {'Distance (cm)', 'Time (s)', 'Velocity (cm/s)'};
            app.ParameterLinkingDropDown.Value = 'Distance (cm)';
            app.PulseCheckBox = uicheckbox(app.LeftPanel);
            app.PulseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseCheckBoxValueChanged, true);
            app.PulseCheckBox.Text = 'Pulse';
            app.PulseTrainCheckBox = uicheckbox(app.LeftPanel);
            app.PulseTrainCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseTrainCheckBoxValueChanged, true);
            app.PulseTrainCheckBox.Text = 'Pulse Train';
            app.GaussianCheckBox = uicheckbox(app.LeftPanel);
            app.GaussianCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianCheckBoxValueChanged, true);
            app.GaussianCheckBox.Text = 'Gaussian';
            app.TriangleCheckBox = uicheckbox(app.LeftPanel);
            app.TriangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriangleCheckBoxValueChanged, true);
            app.TriangleCheckBox.Text = 'Triangle';
            app.SessionSwitchLabel = uilabel(app.LeftPanel);
            app.SessionSwitchLabel.Text = 'Session';
            app.SessionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SessionSwitch.Items = {'Clear', 'On'};
            app.SessionSwitch.ValueChangedFcn = createCallbackFcn(app, @SessionSwitchValueChanged, true);
            app.SessionSwitch.Value = 'Clear';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Stimulus Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Amplitude (V)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controllable_Fgen_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end