classdef Controllable_Fgen_v8_3162020 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DigitalPulseTab                 matlab.ui.container.Tab
        StartcmEditField_4Label_2       matlab.ui.control.Label
        StartcmEditField_4              matlab.ui.control.NumericEditField
        DurationcmormsEditField_2Label  matlab.ui.control.Label
        DurationcmormsEditField_2       matlab.ui.control.NumericEditField
        PulseTab                        matlab.ui.container.Tab
        AmplitudenAEditFieldLabel       matlab.ui.control.Label
        AmplitudenAEditField            matlab.ui.control.NumericEditField
        StartcmEditFieldLabel           matlab.ui.control.Label
        StartcmEditField                matlab.ui.control.NumericEditField
        DurationcmormsEditFieldLabel    matlab.ui.control.Label
        DurationcmormsEditField         matlab.ui.control.NumericEditField
        PulseTrainTab                   matlab.ui.container.Tab
        AmplitudenAEditField_2Label     matlab.ui.control.Label
        AmplitudenAEditField_2          matlab.ui.control.NumericEditField
        StartcmEditField_4Label         matlab.ui.control.Label
        StartcmEditField_2              matlab.ui.control.NumericEditField
        DurationcmEditField_2Label      matlab.ui.control.Label
        DurationcmEditField_2           matlab.ui.control.NumericEditField
        PeriodHzorcmEditFieldLabel      matlab.ui.control.Label
        PeriodHzorcmEditField           matlab.ui.control.NumericEditField
        PulseWidthHzorcmEditFieldLabel  matlab.ui.control.Label
        PulseWidthHzorcmEditField       matlab.ui.control.NumericEditField
        TriangleTab                     matlab.ui.container.Tab
        AmplitudenAEditField_3Label     matlab.ui.control.Label
        AmplitudenAEditField_3          matlab.ui.control.NumericEditField
        StartcmEditField_5Label         matlab.ui.control.Label
        StartcmEditField_3              matlab.ui.control.NumericEditField
        PeakcmEditFieldLabel            matlab.ui.control.Label
        PeakcmEditField                 matlab.ui.control.NumericEditField
        EndcmEditFieldLabel             matlab.ui.control.Label
        EndcmEditField                  matlab.ui.control.NumericEditField
        GaussianTab                     matlab.ui.container.Tab
        AmplitudenAEditField_4Label     matlab.ui.control.Label
        AmplitudenAEditField_4          matlab.ui.control.NumericEditField
        LeftWidthAtHalfMaxEditFieldLabel  matlab.ui.control.Label
        LeftWidthAtHalfMaxEditField     matlab.ui.control.NumericEditField
        CenterEditFieldLabel            matlab.ui.control.Label
        CenterEditField                 matlab.ui.control.NumericEditField
        RightWidthAtHalfMaxLabel        matlab.ui.control.Label
        RightWidthAtHalfMaxEditField    matlab.ui.control.NumericEditField
        ParameterLinkingDropDownLabel   matlab.ui.control.Label
        ParameterLinkingDropDown        matlab.ui.control.DropDown
        PulseCheckBox                   matlab.ui.control.CheckBox
        PulseTrainCheckBox              matlab.ui.control.CheckBox
        GaussianCheckBox                matlab.ui.control.CheckBox
        TriangleCheckBox                matlab.ui.control.CheckBox
        SessionSwitchLabel              matlab.ui.control.Label
        SessionSwitch                   matlab.ui.control.Switch
        UpdateButton                    matlab.ui.control.Button
        COMEditFieldLabel               matlab.ui.control.Label
        COMEditField                    matlab.ui.control.EditField
        DigitalOutCheckBox              matlab.ui.control.CheckBox
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        UpdatePreviewButton_2           matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        param = 'c';
        
        
        %digital Pulse Params
        digStart = 0;
        digDuration = 0;
        digOn = 0;
        
        % pulse params 
        pulseAmp =0; % amplitude of pulse V
        pulseDur = 0; % s
        pulseDly = 0; % s
        pulseOn = 0;
        
        %pulse train params
        trainAmp =0;
        trainDur = 0;
        trainDly = 0;
        trainPrd = 0;
        trainWid = 0;
        trainOn = 0;        
        
        %triangle pulse params 
        triAmp = 0;
        triPk = 0;
        triDly = 0;
        triOn = 0;
        triEnd = 0;
        triDur = 0;
        
        %gaussian params
        gausAmp = 0;
        gausCent = 0;
        gausLeftWid = 0;
        gausRightWid = 0;
        gausOn = 0;      
        
        SerialID
        comNum = 13;
        
        A2V = 0.5;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SessionSwitch
        function SessionSwitchValueChanged(app, event)
            isStartOn  = app.SessionSwitch.Value;
            comStr = sprintf("COM%g", app.comNum);
            switch isStartOn 
                case 'On'
                    coms = instrfind;
                    if ~isempty(coms) 
                        fclose(coms);
                    end
                    
                    app.SerialID = serial(comStr,'BaudRate',9600);
                    fopen(app.SerialID);
%                     clearstr = '<dp0,0.00,000,000!t0,0.00,000,000,00.000,00.000!^0,0.00,000,000,000!g0,0,0,0,0!>';
%                     for char = clearstr
%                         fwrite(app.SerialID, char)
%                     end
                case 'Clear'
                    fclose(app.SerialID);
            end
        end

        % Value changed function: AmplitudenAEditField
        function AmplitudenAEditFieldValueChanged(app, event)
            
            app.pulseAmp = app.AmplitudenAEditField.Value * app.A2V;
            
        end

        % Value changed function: StartcmEditField
        function StartcmEditFieldValueChanged(app, event)
            app.pulseDly = app.StartcmEditField.Value;
            
        end

        % Value changed function: DurationcmormsEditField
        function DurationcmormsEditFieldValueChanged(app, event)
            app.pulseDur = app.DurationcmormsEditField.Value;
            
        end

        % Value changed function: PulseCheckBox
        function PulseCheckBoxValueChanged(app, event)
            app.pulseOn = app.PulseCheckBox.Value;
            
        end

        % Value changed function: AmplitudenAEditField_3
        function AmplitudenAEditField_3ValueChanged(app, event)
            app.triAmp = app.AmplitudenAEditField_3.Value * app.A2V;
            
        end

        % Value changed function: StartcmEditField_3
        function StartcmEditField_3ValueChanged(app, event)
            app.triDly = app.StartcmEditField_3.Value;
            
        end

        % Value changed function: PeakcmEditField
        function PeakcmEditFieldValueChanged(app, event)
            app.triPk = app.PeakcmEditField.Value;
            
        end

        % Value changed function: EndcmEditField
        function EndcmEditFieldValueChanged(app, event)
            app.triEnd = app.EndcmEditField.Value;
            
        end

        % Value changed function: TriangleCheckBox
        function TriangleCheckBoxValueChanged(app, event)
            app.triOn = app.TriangleCheckBox.Value;
        end

        % Value changed function: AmplitudenAEditField_4
        function AmplitudenAEditField_4ValueChanged(app, event)
            app.gausAmp = app.AmplitudenAEditField_4.Value * app.A2V;
           
        end

        % Value changed function: CenterEditField
        function CenterEditFieldValueChanged(app, event)
            app.gausCent = app.CenterEditField.Value;
            % make sure left and right are up to date
            app.gausLeftWid = app.CenterEditField.Value - app.LeftWidthAtHalfMaxEditField.Value;
            app.gausRightWid = app.RightWidthAtHalfMaxEditField.Value - app.CenterEditField.Value;
        end

        % Value changed function: LeftWidthAtHalfMaxEditField
        function LeftWidthAtHalfMaxEditFieldValueChanged(app, event)
            lwhm = app.LeftWidthAtHalfMaxEditField.Value;
            app.gausLeftWid = app.CenterEditField.Value - lwhm;
        end

        % Value changed function: RightWidthAtHalfMaxEditField
        function RightWidthAtHalfMaxEditFieldValueChanged(app, event)
            rwhm = app.RightWidthAtHalfMaxEditField.Value;
            app.gausRightWid = rwhm - app.CenterEditField.Value;
            
        end

        % Value changed function: GaussianCheckBox
        function GaussianCheckBoxValueChanged(app, event)
            app.gausOn = app.GaussianCheckBox.Value;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            value = app.ParameterLinkingDropDown.Value;
                switch value
                    case 'Time'
                        app.param = 's';
                    case 'Velocity (cm/s)'
                        app.param = 'v';   
                    case 'Distance (cm)'
                        app.param = 'd';
                end
            
            % sending direction string to teensy


            UpdatePreviewButton_2Pushed(app, event)
            
            % warnings that fail the update
            if and(xor(app.triOn, app.gausOn), or(app.pulseOn,app.trainOn))
                warning("Cannot have Ramp or Gaussian active with Pulse or Train");
            elseif (and(app.pulseOn,app.trainOn) && (app.pulseAmp + app.trainAmp >6.6))
                warning("Sum of pulse and train amplitudes exceeds maximum output 6.6nA (3.3V)");
            elseif(and(app.pulseOn,app.trainOn) && app.trainPrd == app.trainDur)
                warning("Set pulse train period to a different value than the duration");
            elseif(and(app.triOn, app.gausOn))
                warning("Cannot have Ramp and Gaussian active together");
            elseif(and(app.param=='s', and(app.trainOn, app.trainWid < app.trainPrd)))
                warning("Pulse Width in Hz must be larger than Period");
            elseif(and(app.param == 'd', and(app.trainOn, app.trainWid > app.trainPrd)))
                warning("Pulse Width in cm must be smaller than Period");
            else
                
                % make sure teensy not active
                try 
                    fprintf('Flushing \n')
                    flushinput(app.SerialID);
                catch ME
                    %pass    
                end
                try
                    isReadyToReceive = fread(app.SerialID);
                catch ME
                    warning('Something went wrong on Serial read, try restarting session');
                    return;
                end


                % send time/dist/velo parameter


                % send parameters as string
                app.triDur = app.triEnd-app.triDly;
                
                digStr = sprintf('b%1.0f,%03.0f,%04.0f!', app.digOn, app.digStart, app.digDuration);
                pulseStr = sprintf('p%1.0f,%1.2f,%04.0f,%03.0f!', app.pulseOn, app.pulseAmp, app.pulseDur, app.pulseDly);           
                trainStr = sprintf('t%1.0f,%1.2f,%03.0f,%03.0f,%03.0f,%03.0f!', app.trainOn, app.trainAmp, app.trainDur, app.trainDly, app.trainPrd, app.trainWid);
                triStr = sprintf('^%1.0f,%1.2f,%03.0f,%03.0f,%03.0f!', app.triOn, app.triAmp, app.triDur, app.triPk, app.triDly);
                gausStr = sprintf('g%1.0f,%1.2f,%03.0f,%03.0f,%03.0f!',app.gausOn, app.gausAmp, app.gausCent, app.gausLeftWid, app.gausRightWid);
          
                dirStr = sprintf('<%s%s%s%s%s%s>\n', app.param, digStr,pulseStr, trainStr, triStr, gausStr);
    
                % wait until teensy ready to receive
                if ~isReadyToReceive
                    while(~isReadyToReceive)
                        flushinput(app.SerialID);
                        fprintf("waiting, %g:\n", isReadyToReceive)
                        isReadyToReceive = fread(app.SerialID, 1);
                    end
                end
                
                % transmit data
                tic;
                for char = dirStr
                    try 
                        fwrite(app.SerialID, char);
                    catch 
                        warning('Something went wrong on transmission of %s: Try turning Session On', char);
                    end
                end
                toc;
                fprintf('Transmitted string: %s\n',dirStr);
            end


        end

        % Value changed function: AmplitudenAEditField_2
        function AmplitudenAEditField_2ValueChanged(app, event)
            app.trainAmp = app.AmplitudenAEditField_2.Value *app.A2V;
            
        end

        % Value changed function: StartcmEditField_2
        function StartcmEditField_2ValueChanged(app, event)
            app.trainDly = app.StartcmEditField_2.Value;
            
        end

        % Value changed function: DurationcmEditField_2
        function DurationcmEditField_2ValueChanged(app, event)
            app.trainDur = app.DurationcmEditField_2.Value;
            
        end

        % Value changed function: PeriodHzorcmEditField
        function PeriodHzorcmEditFieldValueChanged(app, event)
            app.trainPrd = app.PeriodHzorcmEditField.Value;
            
        end

        % Value changed function: PulseWidthHzorcmEditField
        function PulseWidthHzorcmEditFieldValueChanged(app, event)
            app.trainWid = app.PulseWidthHzorcmEditField.Value;
            
        end

        % Value changed function: PulseTrainCheckBox
        function PulseTrainCheckBoxValueChanged(app, event)
            app.trainOn = app.PulseTrainCheckBox.Value;
            
        end

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)
            app.comNum = str2num(app.COMEditField.Value);
      
        end

        % Button pushed function: UpdatePreviewButton_2
        function UpdatePreviewButton_2Pushed(app, event)
            y = zeros(1,181);
            pulseTime_y =zeros(1,1001);
            dPulse_y = zeros(1,1001);
            spike_y = zeros(1,1001);
            
            if app.digOn
                if  strcmp(app.ParameterLinkingDropDown.Value, 'Distance (cm)')
                  y((app.digStart+1):(app.digStart+app.digDuration)) = y((app.digStart+1):(app.digStart+app.digDuration)) + 1;
                elseif strcmp(app.ParameterLinkingDropDown.Value, 'Time')
                  y((app.digStart+1)) = y((app.digStart+1)) + 1;
                  dPulse_y(1:app.digDuration) = 1;
                end
            end
            
            if app.pulseOn
              if  strcmp(app.ParameterLinkingDropDown.Value, 'Distance (cm)')
                  y((app.pulseDly+1):(app.pulseDly+app.pulseDur)) = y((app.pulseDly+1):(app.pulseDly+app.pulseDur)) + app.pulseAmp/app.A2V;
              elseif strcmp(app.ParameterLinkingDropDown.Value, 'Time')
                  y((app.pulseDly+1):(app.pulseDly+5)) = y((app.pulseDly+1):(app.pulseDly+5)) + app.pulseAmp/app.A2V;
                  pulseTime_y(1:app.pulseDur) = app.pulseAmp/app.A2V;
              end
            end
            
            if app.trainOn
                if  strcmp(app.ParameterLinkingDropDown.Value, 'Distance (cm)')
                    single_pulse = zeros(1,app.trainPrd); 
                    single_pulse(1:app.trainWid) = app.trainAmp/app.A2V;
                    all_pulses = repmat(single_pulse, 1, ceil(app.trainDur/app.trainPrd));
                    all_pulses = all_pulses(1:app.trainDur);
                    
                    train_inds = [(app.trainDly+1):(app.trainDly+app.trainDur)];
                    y(train_inds) = y(train_inds) + all_pulses;    
                
                elseif strcmp(app.ParameterLinkingDropDown.Value, 'Time')

                    prdTs = round(1/app.trainPrd*1000);
                    width = round(1/app.trainWid*1000);
                    numIn1s = ceil(1000/prdTs);
                    single_spike = zeros(1,prdTs);
                    single_spike(1:width) = app.trainAmp/app.A2V;
                    all_spikes = repmat(single_spike, 1, numIn1s);
                    spike_y = all_spikes(1:1000);
                    
                    sample_spikes = zeros(1,app.trainDur);
                    sample_spikes(1:2:end) = app.trainAmp/app.A2V;
                    y((app.trainDly+1):(app.trainDly+app.trainDur)) = y((app.trainDly+1):(app.trainDly+app.trainDur)) + sample_spikes;


                end
                
            end
            
            if app.triOn
                currTriAmp = app.triAmp/app.A2V;
                triSlopeUp = currTriAmp/(app.triPk-app.triDly);
                triSlopeDown = currTriAmp/(app.triPk-app.triEnd);
                triPulseUp = ((0:(app.triPk-app.triDly-1)))*triSlopeUp;
                triPulseDown = ((app.triPk:app.triEnd) - app.triPk)*triSlopeDown+currTriAmp;
                
                triPulse = [triPulseUp,triPulseDown];
                triInds = (app.triDly+1):(app.triEnd+1);
                %fprintf("size UP:%g, size down: %g, indSize:%g \n", length(triPulseUp), length(triPulseDown), length(triInds))
               
                
                y(triInds) = y(triInds)+triPulse;
            end
            
            if app.gausOn
                currGausAmp = app.gausAmp/app.A2V;
                gausInds = cumsum(ones(1,181));
                % up gaussian
                upInds = 1:(app.gausCent+1);
                y(upInds) = currGausAmp*exp(-log(2)*((gausInds(upInds)-app.gausCent)/app.gausLeftWid).^2);
                
                % down gaussian
                downInds = (app.gausCent+1):170;
                y(downInds) = currGausAmp*exp(-log(2)*((gausInds(downInds)-app.gausCent)/app.gausRightWid).^2);
                %fprintf ("Amp: %g, Cent: %g, L:%g, R:%g", app.gausAmp, app.gausCent, app.gausLeftWid, app.gausRightWid)
            end


            plot(app.UIAxes, y);
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, spike_y)
            plot(app.UIAxes2, dPulse_y)
            plot(app.UIAxes2, pulseTime_y)
            legend(app.UIAxes2, {'Spike Train','Digital Pulse','Analog Pulse'})
            hold(app.UIAxes2, 'off')
        end

        % Value changed function: DigitalOutCheckBox
        function DigitalOutCheckBoxValueChanged(app, event)
            app.digOn = app.DigitalOutCheckBox.Value;
            
        end

        % Value changed function: StartcmEditField_4
        function StartcmEditField_4ValueChanged(app, event)
            app.digStart = app.StartcmEditField_4.Value;
            
        end

        % Value changed function: DurationcmormsEditField_2
        function DurationcmormsEditField_2ValueChanged(app, event)
            app.digDuration = app.DurationcmormsEditField_2.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {489, 489};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {409, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DigitalPulseTab = uitab(app.TabGroup);
            app.DigitalPulseTab.Title = 'Digital Pulse';
            app.StartcmEditField_4Label_2 = uilabel(app.DigitalPulseTab);
            app.StartcmEditField_4Label_2.Text = 'Start (cm)';
            app.StartcmEditField_4 = uieditfield(app.DigitalPulseTab, 'numeric');
            app.StartcmEditField_4.Limits = [0 180];
            app.StartcmEditField_4.ValueDisplayFormat = '%3.0f';
            app.StartcmEditField_4.ValueChangedFcn = createCallbackFcn(app, @StartcmEditField_4ValueChanged, true);
            app.DurationcmormsEditField_2Label = uilabel(app.DigitalPulseTab);
            app.DurationcmormsEditField_2Label.Text = 'Duration (cm or ms)';
            app.DurationcmormsEditField_2 = uieditfield(app.DigitalPulseTab, 'numeric');
            app.DurationcmormsEditField_2.Limits = [0 9999];
            app.DurationcmormsEditField_2.ValueDisplayFormat = '%3.0f';
            app.DurationcmormsEditField_2.ValueChangedFcn = createCallbackFcn(app, @DurationcmormsEditField_2ValueChanged, true);
            app.PulseTab = uitab(app.TabGroup);
            app.PulseTab.Title = 'Pulse';
            app.AmplitudenAEditFieldLabel = uilabel(app.PulseTab);
            app.AmplitudenAEditFieldLabel.Text = 'Amplitude (nA)';
            app.AmplitudenAEditField = uieditfield(app.PulseTab, 'numeric');
            app.AmplitudenAEditField.Limits = [0 6.6];
            app.AmplitudenAEditField.ValueDisplayFormat = '%1.2f';
            app.AmplitudenAEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudenAEditFieldValueChanged, true);
            app.StartcmEditFieldLabel = uilabel(app.PulseTab);
            app.StartcmEditFieldLabel.Text = 'Start (cm)';
            app.StartcmEditField = uieditfield(app.PulseTab, 'numeric');
            app.StartcmEditField.Limits = [0 180];
            app.StartcmEditField.ValueDisplayFormat = '%3.0f';
            app.StartcmEditField.ValueChangedFcn = createCallbackFcn(app, @StartcmEditFieldValueChanged, true);
            app.DurationcmormsEditFieldLabel = uilabel(app.PulseTab);
            app.DurationcmormsEditFieldLabel.Text = 'Duration (cm or ms)';
            app.DurationcmormsEditField = uieditfield(app.PulseTab, 'numeric');
            app.DurationcmormsEditField.Limits = [0 9999];
            app.DurationcmormsEditField.ValueDisplayFormat = '%4.0f';
            app.DurationcmormsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationcmormsEditFieldValueChanged, true);
            app.PulseTrainTab = uitab(app.TabGroup);
            app.PulseTrainTab.Title = 'Pulse Train';
            app.AmplitudenAEditField_2Label = uilabel(app.PulseTrainTab);
            app.AmplitudenAEditField_2Label.Text = 'Amplitude (nA)';
            app.AmplitudenAEditField_2 = uieditfield(app.PulseTrainTab, 'numeric');
            app.AmplitudenAEditField_2.Limits = [0 6.6];
            app.AmplitudenAEditField_2.ValueDisplayFormat = '%1.2f';
            app.AmplitudenAEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudenAEditField_2ValueChanged, true);
            app.StartcmEditField_4Label = uilabel(app.PulseTrainTab);
            app.StartcmEditField_4Label.Text = 'Start (cm)';
            app.StartcmEditField_2 = uieditfield(app.PulseTrainTab, 'numeric');
            app.StartcmEditField_2.Limits = [0 180];
            app.StartcmEditField_2.ValueDisplayFormat = '%3.0f';
            app.StartcmEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartcmEditField_2ValueChanged, true);
            app.DurationcmEditField_2Label = uilabel(app.PulseTrainTab);
            app.DurationcmEditField_2Label.Text = 'Duration (cm)';
            app.DurationcmEditField_2 = uieditfield(app.PulseTrainTab, 'numeric');
            app.DurationcmEditField_2.Limits = [0 180];
            app.DurationcmEditField_2.ValueDisplayFormat = '%3.0f';
            app.DurationcmEditField_2.ValueChangedFcn = createCallbackFcn(app, @DurationcmEditField_2ValueChanged, true);
            app.PeriodHzorcmEditFieldLabel = uilabel(app.PulseTrainTab);
            app.PeriodHzorcmEditFieldLabel.Text = 'Period (Hz or cm)';
            app.PeriodHzorcmEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.PeriodHzorcmEditField.Limits = [0 70];
            app.PeriodHzorcmEditField.ValueDisplayFormat = '%.0f';
            app.PeriodHzorcmEditField.ValueChangedFcn = createCallbackFcn(app, @PeriodHzorcmEditFieldValueChanged, true);
            app.PulseWidthHzorcmEditFieldLabel = uilabel(app.PulseTrainTab);
            app.PulseWidthHzorcmEditFieldLabel.Text = 'Pulse Width (Hz or cm)';
            app.PulseWidthHzorcmEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.PulseWidthHzorcmEditField.Limits = [0 Inf];
            app.PulseWidthHzorcmEditField.ValueDisplayFormat = '%.0f';
            app.PulseWidthHzorcmEditField.ValueChangedFcn = createCallbackFcn(app, @PulseWidthHzorcmEditFieldValueChanged, true);
            app.TriangleTab = uitab(app.TabGroup);
            app.TriangleTab.Title = 'Triangle';
            app.AmplitudenAEditField_3Label = uilabel(app.TriangleTab);
            app.AmplitudenAEditField_3Label.Text = 'Amplitude (nA)';
            app.AmplitudenAEditField_3 = uieditfield(app.TriangleTab, 'numeric');
            app.AmplitudenAEditField_3.Limits = [0 6.6];
            app.AmplitudenAEditField_3.ValueDisplayFormat = '%1.2f';
            app.AmplitudenAEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudenAEditField_3ValueChanged, true);
            app.StartcmEditField_5Label = uilabel(app.TriangleTab);
            app.StartcmEditField_5Label.Text = 'Start (cm)';
            app.StartcmEditField_3 = uieditfield(app.TriangleTab, 'numeric');
            app.StartcmEditField_3.Limits = [0 180];
            app.StartcmEditField_3.ValueDisplayFormat = '%3.0f';
            app.StartcmEditField_3.ValueChangedFcn = createCallbackFcn(app, @StartcmEditField_3ValueChanged, true);
            app.PeakcmEditFieldLabel = uilabel(app.TriangleTab);
            app.PeakcmEditFieldLabel.Text = 'Peak (cm)';
            app.PeakcmEditField = uieditfield(app.TriangleTab, 'numeric');
            app.PeakcmEditField.ValueDisplayFormat = '%3.0f';
            app.PeakcmEditField.ValueChangedFcn = createCallbackFcn(app, @PeakcmEditFieldValueChanged, true);
            app.EndcmEditFieldLabel = uilabel(app.TriangleTab);
            app.EndcmEditFieldLabel.Text = 'End (cm';
            app.EndcmEditField = uieditfield(app.TriangleTab, 'numeric');
            app.EndcmEditField.ValueDisplayFormat = '%3.0f';
            app.EndcmEditField.ValueChangedFcn = createCallbackFcn(app, @EndcmEditFieldValueChanged, true);
            app.GaussianTab = uitab(app.TabGroup);
            app.GaussianTab.Title = 'Gaussian';
            app.AmplitudenAEditField_4Label = uilabel(app.GaussianTab);
            app.AmplitudenAEditField_4Label.Text = 'Amplitude (nA)';
            app.AmplitudenAEditField_4 = uieditfield(app.GaussianTab, 'numeric');
            app.AmplitudenAEditField_4.Limits = [0 6.6];
            app.AmplitudenAEditField_4.ValueDisplayFormat = '%1.2f';
            app.AmplitudenAEditField_4.ValueChangedFcn = createCallbackFcn(app, @AmplitudenAEditField_4ValueChanged, true);
            app.LeftWidthAtHalfMaxEditFieldLabel = uilabel(app.GaussianTab);
            app.LeftWidthAtHalfMaxEditFieldLabel.Text = 'Left Width At Half Max';
            app.LeftWidthAtHalfMaxEditField = uieditfield(app.GaussianTab, 'numeric');
            app.LeftWidthAtHalfMaxEditField.Limits = [0 180];
            app.LeftWidthAtHalfMaxEditField.ValueDisplayFormat = '%3.0f';
            app.LeftWidthAtHalfMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LeftWidthAtHalfMaxEditFieldValueChanged, true);
            app.CenterEditFieldLabel = uilabel(app.GaussianTab);
            app.CenterEditFieldLabel.Text = 'Center';
            app.CenterEditField = uieditfield(app.GaussianTab, 'numeric');
            app.CenterEditField.Limits = [0 170];
            app.CenterEditField.ValueDisplayFormat = '%3.0f';
            app.CenterEditField.ValueChangedFcn = createCallbackFcn(app, @CenterEditFieldValueChanged, true);
            app.RightWidthAtHalfMaxLabel = uilabel(app.GaussianTab);
            app.RightWidthAtHalfMaxLabel.Text = 'Right Width At Half Max';
            app.RightWidthAtHalfMaxEditField = uieditfield(app.GaussianTab, 'numeric');
            app.RightWidthAtHalfMaxEditField.Limits = [0 180];
            app.RightWidthAtHalfMaxEditField.ValueDisplayFormat = '%3.0f';
            app.RightWidthAtHalfMaxEditField.ValueChangedFcn = createCallbackFcn(app, @RightWidthAtHalfMaxEditFieldValueChanged, true);
            app.ParameterLinkingDropDownLabel = uilabel(app.LeftPanel);
            app.ParameterLinkingDropDownLabel.Text = 'Parameter Linking';
            app.ParameterLinkingDropDown = uidropdown(app.LeftPanel);
            app.ParameterLinkingDropDown.Items = {'Distance (cm)', 'Time'};
            app.ParameterLinkingDropDown.Value = 'Distance (cm)';
            app.PulseCheckBox = uicheckbox(app.LeftPanel);
            app.PulseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseCheckBoxValueChanged, true);
            app.PulseCheckBox.Text = 'Pulse';
            app.PulseTrainCheckBox = uicheckbox(app.LeftPanel);
            app.PulseTrainCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseTrainCheckBoxValueChanged, true);
            app.PulseTrainCheckBox.Text = 'Pulse Train';
            app.GaussianCheckBox = uicheckbox(app.LeftPanel);
            app.GaussianCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianCheckBoxValueChanged, true);
            app.GaussianCheckBox.Text = 'Gaussian';
            app.TriangleCheckBox = uicheckbox(app.LeftPanel);
            app.TriangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriangleCheckBoxValueChanged, true);
            app.TriangleCheckBox.Text = 'Triangle';
            app.SessionSwitchLabel = uilabel(app.LeftPanel);
            app.SessionSwitchLabel.Text = 'Session';
            app.SessionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SessionSwitch.Items = {'Clear', 'On'};
            app.SessionSwitch.ValueChangedFcn = createCallbackFcn(app, @SessionSwitchValueChanged, true);
            app.SessionSwitch.Value = 'Clear';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.COMEditFieldLabel = uilabel(app.LeftPanel);
            app.COMEditFieldLabel.Text = 'COM #';
            app.COMEditField = uieditfield(app.LeftPanel, 'text');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = '13';
            app.DigitalOutCheckBox = uicheckbox(app.LeftPanel);
            app.DigitalOutCheckBox.ValueChangedFcn = createCallbackFcn(app, @DigitalOutCheckBoxValueChanged, true);
            app.DigitalOutCheckBox.Text = 'Digital Out';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Stimulus Preview')
            xlabel(app.UIAxes, 'Distance (cm)')
            ylabel(app.UIAxes, 'Amplitude (nA)')
            app.UIAxes.XLim = [0 180];
            app.UIAxes.YLim = [0 6.6];
            app.UIAxes.XTick = [0 20 40 60 80 100 120 140 160 180];
            app.UpdatePreviewButton_2 = uibutton(app.RightPanel, 'push');
            app.UpdatePreviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButton_2Pushed, true);
            app.UpdatePreviewButton_2.Text = 'Update Preview';
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Time-Based Stimuli')
            xlabel(app.UIAxes2, 'Time (ms)')
            ylabel(app.UIAxes2, 'Amplitude (nA)')
            app.UIAxes2.XLim = [0 1000];
            app.UIAxes2.YLim = [0 6.6];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controllable_Fgen_v8_3162020

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end