classdef Controllable_Fgen_v13_TwoTrainsFaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        DigitalPulseTab                matlab.ui.container.Tab
        StartcmEditField_4Label_2      matlab.ui.control.Label
        DigitalStartEditField          matlab.ui.control.NumericEditField
        DurationEditField_2Label       matlab.ui.control.Label
        DigitalDurationEditField       matlab.ui.control.NumericEditField
        DigitalDurationDropDown        matlab.ui.control.DropDown
        PulseTab                       matlab.ui.container.Tab
        AmplitudenAEditFieldLabel      matlab.ui.control.Label
        PulseAmpEditField              matlab.ui.control.NumericEditField
        StartcmEditFieldLabel          matlab.ui.control.Label
        PulseStartEditField            matlab.ui.control.NumericEditField
        DurationEditFieldLabel         matlab.ui.control.Label
        PulseDurationEditField         matlab.ui.control.NumericEditField
        PulseDropDown                  matlab.ui.control.DropDown
        PulseTrain1Tab                 matlab.ui.container.Tab
        AmplitudenAEditField_2Label    matlab.ui.control.Label
        TrainAmpEditField              matlab.ui.control.NumericEditField
        StartcmEditField_4Label        matlab.ui.control.Label
        TrainStartEditField            matlab.ui.control.NumericEditField
        DurationEditField_3Label       matlab.ui.control.Label
        TrainDurationEditField         matlab.ui.control.NumericEditField
        PeriodEditFieldLabel           matlab.ui.control.Label
        TrainPeriodEditField           matlab.ui.control.NumericEditField
        PulseWidthEditFieldLabel       matlab.ui.control.Label
        TrainWidthEditField            matlab.ui.control.NumericEditField
        TrainParamDropDown             matlab.ui.control.DropDown
        SpikeParamDropDown             matlab.ui.control.DropDown
        PulseTrain2Tab                 matlab.ui.container.Tab
        AmplitudenAEditField_2Label_2  matlab.ui.control.Label
        TrainAmpEditField_2            matlab.ui.control.NumericEditField
        StartcmEditField_4Label_3      matlab.ui.control.Label
        TrainStartEditField_2          matlab.ui.control.NumericEditField
        DurationEditField_3Label_2     matlab.ui.control.Label
        TrainDurationEditField_2       matlab.ui.control.NumericEditField
        PeriodEditFieldLabel_2         matlab.ui.control.Label
        TrainPeriodEditField_2         matlab.ui.control.NumericEditField
        PulseWidthEditFieldLabel_2     matlab.ui.control.Label
        TrainWidthEditField_2          matlab.ui.control.NumericEditField
        TrainParamDropDown_2           matlab.ui.control.DropDown
        SpikeParamDropDown_2           matlab.ui.control.DropDown
        TriangleTab                    matlab.ui.container.Tab
        AmplitudenAEditField_3Label    matlab.ui.control.Label
        AmplitudenAEditField_3         matlab.ui.control.NumericEditField
        StartcmEditField_5Label        matlab.ui.control.Label
        StartcmEditField_3             matlab.ui.control.NumericEditField
        PeakcmEditFieldLabel           matlab.ui.control.Label
        PeakcmEditField                matlab.ui.control.NumericEditField
        EndcmLabel                     matlab.ui.control.Label
        EndcmEditField                 matlab.ui.control.NumericEditField
        GaussianTab                    matlab.ui.container.Tab
        AmplitudenAEditField_4Label    matlab.ui.control.Label
        AmplitudenAEditField_4         matlab.ui.control.NumericEditField
        LeftWidthAtHalfMaxEditFieldLabel  matlab.ui.control.Label
        LeftWidthAtHalfMaxEditField    matlab.ui.control.NumericEditField
        CenterEditFieldLabel           matlab.ui.control.Label
        CenterEditField                matlab.ui.control.NumericEditField
        RightWidthAtHalfMaxLabel       matlab.ui.control.Label
        RightWidthAtHalfMaxEditField   matlab.ui.control.NumericEditField
        PulseCheckBox                  matlab.ui.control.CheckBox
        PulseTrain1CheckBox            matlab.ui.control.CheckBox
        GaussianCheckBox               matlab.ui.control.CheckBox
        TriangleCheckBox               matlab.ui.control.CheckBox
        SessionSwitchLabel             matlab.ui.control.Label
        SessionSwitch                  matlab.ui.control.Switch
        UpdateButton                   matlab.ui.control.Button
        COMEditFieldLabel              matlab.ui.control.Label
        COMEditField                   matlab.ui.control.EditField
        DigitalPulseCheckBox           matlab.ui.control.CheckBox
        PulseTrain2CheckBox            matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        UpdatePreviewButton_2          matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)        
        
        %digital Pulse Params
        digStart = 0;
        digDuration = 0;
        digParam = 's';
        digOn = 0;
        
        % pulse params 
        pulseAmp =0; % amplitude of pulse V
        pulseDur = 0; % s
        pulseDly = 0; % s
        pulseOn = 0;
        pulseParam = 's'
        
        %pulse train 1 params
        trainAmp =0;
        trainDur = 0;
        trainDly = 0;
        trainPrd = 0;
        trainWid = 0;
        trainOn = 0; 
        trainParam = 's';
        spikeParam = 's';
        
        %pulse train 2 params
        train2Amp =0;
        train2Dur = 0;
        train2Dly = 0;
        train2Prd = 0;
        train2Wid = 0;
        train2On = 0; 
        train2Param = 's';
        spike2Param = 's';
        
        %triangle pulse params 
        triAmp = 0;
        triPk = 0;
        triDly = 0;
        triOn = 0;
        triEnd = 0;
        triDur = 0;
        
        %gaussian params
        gausAmp = 0;
        gausCent = 0;
        gausLeftWid = 0;
        gausRightWid = 0;
        gausOn = 0;      
        
        SerialID
        comNum = 4;
        
        A2V = 0.5;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SessionSwitch
        function SessionSwitchValueChanged(app, event)
            isStartOn  = app.SessionSwitch.Value;
            comStr = sprintf("COM%g", app.comNum);
            switch isStartOn 
                case 'On'
                    coms = instrfind;
                    if ~isempty(coms) 
                        fclose(coms);
                    end
                    
                    app.SerialID = serial(comStr,'BaudRate',9600);
                    fopen(app.SerialID);
%                     clearstr = '<dp0,0.00,000,000!t0,0.00,000,000,00.000,00.000!^0,0.00,000,000,000!g0,0,0,0,0!>';
%                     for char = clearstr
%                         fwrite(app.SerialID, char)
%                     end
                case 'Clear'
                    fclose(app.SerialID);
            end
        end

        % Value changed function: PulseAmpEditField
        function PulseAmpEditFieldValueChanged(app, event)
            
            app.pulseAmp = app.PulseAmpEditField.Value * app.A2V;
            
        end

        % Value changed function: PulseStartEditField
        function PulseStartEditFieldValueChanged(app, event)
            app.pulseDly = app.PulseStartEditField.Value;
            
        end

        % Value changed function: PulseDurationEditField
        function PulseDurationEditFieldValueChanged(app, event)
            app.pulseDur = app.PulseDurationEditField.Value;
            
        end

        % Value changed function: PulseCheckBox
        function PulseCheckBoxValueChanged(app, event)
            app.pulseOn = app.PulseCheckBox.Value;
            
        end

        % Value changed function: AmplitudenAEditField_3
        function AmplitudenAEditField_3ValueChanged(app, event)
            app.triAmp = app.AmplitudenAEditField_3.Value * app.A2V;
            
        end

        % Value changed function: StartcmEditField_3
        function StartcmEditField_3ValueChanged(app, event)
            app.triDly = app.StartcmEditField_3.Value;
            
        end

        % Value changed function: PeakcmEditField
        function PeakcmEditFieldValueChanged(app, event)
            app.triPk = app.PeakcmEditField.Value;
            
        end

        % Value changed function: EndcmEditField
        function EndcmEditFieldValueChanged(app, event)
            app.triEnd = app.EndcmEditField.Value;
            
        end

        % Value changed function: TriangleCheckBox
        function TriangleCheckBoxValueChanged(app, event)
            app.triOn = app.TriangleCheckBox.Value;
        end

        % Value changed function: AmplitudenAEditField_4
        function AmplitudenAEditField_4ValueChanged(app, event)
            app.gausAmp = app.AmplitudenAEditField_4.Value * app.A2V;
           
        end

        % Value changed function: CenterEditField
        function CenterEditFieldValueChanged(app, event)
            app.gausCent = app.CenterEditField.Value;
            % make sure left and right are up to date
            app.gausLeftWid = app.CenterEditField.Value - app.LeftWidthAtHalfMaxEditField.Value;
            app.gausRightWid = app.RightWidthAtHalfMaxEditField.Value - app.CenterEditField.Value;
        end

        % Value changed function: LeftWidthAtHalfMaxEditField
        function LeftWidthAtHalfMaxEditFieldValueChanged(app, event)
            lwhm = app.LeftWidthAtHalfMaxEditField.Value;
            app.gausLeftWid = app.CenterEditField.Value - lwhm;
        end

        % Value changed function: RightWidthAtHalfMaxEditField
        function RightWidthAtHalfMaxEditFieldValueChanged(app, event)
            rwhm = app.RightWidthAtHalfMaxEditField.Value;
            app.gausRightWid = rwhm - app.CenterEditField.Value;
            
        end

        % Value changed function: GaussianCheckBox
        function GaussianCheckBoxValueChanged(app, event)
            app.gausOn = app.GaussianCheckBox.Value;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            % overlapping trains
            assumedTopSpeed = 100/1000; %cm/ms
            bothTrainsOn = and(app.trainOn, app.train2On);
            distanceOverlap = and(app.trainParam == 'd',(app.train2Dly<(app.trainDly+app.trainDur)));
            timeOverlap = and(app.trainParam == 's', (app.train2Dly<(app.trainDly+app.trainDur*assumedTopSpeed)));
            
            %distance duration train 1
            % pulse distance duration
            analogDistDistOverlap1 = and(and(app.trainParam == 'd', app.pulseParam =='d'),or(and(app.pulseDly<(app.trainDly+app.trainDur),app.pulseDly>app.trainDly), and(app.trainDly<(app.pulseDly+app.pulseDur), app.trainDly>app.pulseDly)));
            % pulse time duration
            analogDistTimeOverlap1 = and(and(app.trainParam == 'd', app.pulseParam =='s'),or(and(app.pulseDly<(app.trainDly+app.trainDur),app.pulseDly>app.trainDly), and(app.trainDly<(app.pulseDly+app.pulseDur*assumedTopSpeed), app.trainDly>app.pulseDly)));

            % time duration train 1                       
            analogTimeDistOverlap1 = and(and(app.trainParam == 's', app.pulseParam =='d'), or(and(app.pulseDly<(app.trainDly+app.trainDur*assumedTopSpeed),app.pulseDly>app.trainDly), and(app.trainDly<(app.pulseDly+app.pulseDur), app.trainDly>app.pulseDly)));       
            analogTimeTimeOverlap1 = and(and(app.trainParam == 's', app.pulseParam =='s'), or(and(app.pulseDly<(app.trainDly+app.trainDur*assumedTopSpeed),app.pulseDly>app.trainDly), and(app.trainDly<(app.pulseDly+app.pulseDur*assumedTopSpeed), app.trainDly>app.pulseDly)));       
            analogOverlap1 = (analogDistDistOverlap1 || analogDistTimeOverlap1 || analogTimeDistOverlap1 || analogTimeTimeOverlap1);
            
            % distance duration train 2
            % pulse distance duration
            analogDistDistOverlap2 = and(and(app.train2Param == 'd', app.pulseParam =='d'),or(and(app.pulseDly<(app.train2Dly+app.train2Dur),app.pulseDly>app.train2Dly), and(app.train2Dly<(app.pulseDly+app.pulseDur), app.train2Dly>app.pulseDly)));
            % pulse time duration
            analogDistTimeOverlap2 = and(and(app.train2Param == 'd', app.pulseParam =='s'),or(and(app.pulseDly<(app.train2Dly+app.train2Dur*assumedTopSpeed),app.pulseDly>app.train2Dly), and(app.train2Dly<(app.pulseDly+app.pulseDur*assumedTopSpeed), app.train2Dly>app.pulseDly)));

            % time duration train 1                       
            analogTimeDistOverlap2 = and(and(app.train2Param == 's', app.pulseParam =='d'), or(and(app.pulseDly<(app.train2Dly+app.train2Dur),app.pulseDly>app.train2Dly), and(app.train2Dly<(app.pulseDly+app.pulseDur), app.train2Dly>app.pulseDly)));       
            analogTimeTimeOverlap2 = and(and(app.train2Param == 's', app.pulseParam =='s'), or(and(app.pulseDly<(app.train2Dly+app.train2Dur*assumedTopSpeed),app.pulseDly>app.train2Dly), and(app.train2Dly<(app.pulseDly+app.pulseDur*assumedTopSpeed), app.train2Dly>app.pulseDly)));       
            analogOverlap2 = (analogDistDistOverlap2 || analogDistTimeOverlap2 || analogTimeDistOverlap2 || analogTimeTimeOverlap2);


            % warnings that fail the update
            if and(bothTrainsOn,or(distanceOverlap, timeOverlap))
                warning("Pulse Train 2 cannot start until pulse train 1 ends");
            elseif and(xor(app.triOn, app.gausOn), or(app.trainOn,(app.pulseOn || app.train2On)))          
                warning("Cannot have Ramp or Gaussian active with Train or Analog Pulse. Use Digital Pulse");
            elseif (and(app.pulseOn,app.trainOn) && analogOverlap1 && (app.pulseAmp + app.trainAmp >3.3))
                warning("Sum of concurrent pulse and train 1 amplitudes exceeds maximum output 6.6nA (3.3V)");
            elseif(and(app.pulseOn,app.train2On) && analogOverlap2 && (app.pulseAmp + app.train2Amp >3.3))
                warning("Sum of concurrent pulse and train 2 amplitudes exceeds maximum output 6.6nA (3.3V)");
            elseif(and(app.triOn, app.gausOn))
                warning("Cannot have Ramp and Gaussian active together");
            elseif(app.digOn && app.digParam == 'd' && (app.digDuration+app.digStart)>170)
                warning("Digital Pulse Cannot last longer than 170cm")
            elseif(app.pulseOn && app.pulseParam == 'd' && (app.pulseDur + app.pulseDly)>170)
                warning("Analog Pulse cannot last longer than 170cm")
            elseif(app.trainOn && app.trainParam=='d' && (app.trainDly+app.trainDur)>170)
                warning("Train cannot last longer than 170cm")
            elseif(and(app.spikeParam=='s', and(app.trainOn, app.trainWid < app.trainPrd)))
                warning("Pulse Width in Hz must be larger than Period");
            elseif(and(app.spikeParam == 'd', and(app.trainOn, app.trainWid > app.trainPrd)))
                warning("Pulse Width in cm must be smaller than Period");
            elseif(app.trainOn && and(app.trainParam==app.spikeParam, app.trainPrd == app.trainDur))
                warning("Set pulse train period to a different value than its duration");
            elseif(app.train2On && app.train2Param=='d' && (app.train2Dly+app.train2Dur)>170)
                warning("Train cannot last longer than 170cm")
            elseif(and(app.spike2Param=='s', and(app.train2On, app.train2Wid < app.train2Prd)))
                warning("Pulse Width in Hz must be larger than Period");
            elseif(and(app.spike2Param == 'd', and(app.train2On, app.train2Wid > app.train2Prd)))
                warning("Pulse Width in cm must be smaller than Period");
            elseif(app.train2On && and(app.train2Param==app.spike2Param, app.train2Prd == app.train2Dur))
                warning("Set pulse train period to a different value than its duration");
            elseif(app.triOn && (app.triDly>app.triPk || app.triPk>app.triEnd))
                warning("Triangle Pulse distances must ascend. Check inputs again.")
            elseif(app.gausOn && (app.gausLeftWid<1 || 1>app.gausRightWid))
                warning("Gaussian distances must ascend. Check inputs again.")
            else
            
                % warnings that could cause problems
                if(app.digOn && app.digParam == 's' && (app.digStart +app.digDuration/25)>170)
                    warning("Digital Pulse may outlast 170 cm")
                end
                if(app.pulseOn && app.pulseParam =='s' && (app.pulseDly+app.pulseDur/25)>170)
                    warning("Analog Pulse may outlast 170cm")
                end
                if(app.trainOn && app.trainParam=='s' && (app.trainDly+app.trainDur/25)>170)
                    warning("Train 1 may outlast 170cm")
                end    
                if(app.train2On && app.train2Param=='s' && (app.train2Dly+app.train2Dur/25)>170)
                    warning("Train 2 may outlast 170cm")
                end    
            
                % show preview
                UpdatePreviewButton_2Pushed(app, event)
            
                % make sure teensy not active
                try 
                    fprintf('Flushing \n')
                    flushinput(app.SerialID);
                catch ME
                    %pass    
                end
                try
                    isReadyToReceive = fread(app.SerialID,1, 'char') == 49;
                    fprintf("Read   : %g\n",isReadyToReceive);
                catch ME
                    warning('Something went wrong on Serial read, try restarting session');
                    return;
                end
                
                
                % send parameters as string
                app.triDur = app.triEnd-app.triDly;
                
                digStr = sprintf('b%1.0f,%03.0f,%s,%04.0f!', app.digOn, app.digStart, app.digParam, app.digDuration);
                pulseStr = sprintf('p%1.0f,%1.2f,%03.0f,%s,%04.0f!', app.pulseOn, app.pulseAmp,  app.pulseDly, app.pulseParam, app.pulseDur);           
                trainStr = sprintf('t%1.0f,%1.2f,%03.0f,%s,%04.0f,%s,%03.0f,%03.0f!', app.trainOn, app.trainAmp, app.trainDly, app.trainParam, app.trainDur, app.spikeParam, app.trainPrd, app.trainWid);
                train2Str = sprintf('a%1.0f,%1.2f,%03.0f,%s,%04.0f,%s,%03.0f,%03.0f!', app.train2On, app.train2Amp, app.train2Dly, app.train2Param, app.train2Dur, app.spike2Param, app.train2Prd, app.train2Wid);
                triStr = sprintf('^%1.0f,%1.2f,%03.0f,%03.0f,%03.0f!', app.triOn, app.triAmp, app.triDur, app.triPk, app.triDly);
                gausStr = sprintf('g%1.0f,%1.2f,%03.0f,%03.0f,%03.0f!',app.gausOn, app.gausAmp, app.gausCent, app.gausLeftWid, app.gausRightWid);
          
                dirStr = sprintf('<%s%s%s%s%s%s>\n', digStr,pulseStr, trainStr, train2Str, triStr, gausStr);
                % wait until teensy ready to receive
                
                t0 = tic;
                if ~isReadyToReceive
                    while(~isReadyToReceive)
                        flushinput(app.SerialID);                        
                        isReadyToReceive = fread(app.SerialID, 1) == 49;
                        fprintf("waiting, %g:\n", isReadyToReceive);
                    end
                end
                
                % transmit data
                
                for char = dirStr
                    try 
                        fwrite(app.SerialID, char);
                    catch 
                        warning('Something went wrong on transmission of %s: Try turning Session On', char);
                    end
                end
                dt = toc(t0);
                fprintf('Elapsed time is %g seconds.\n', dt);
                fprintf('Transmitted string: %s\n',dirStr);
            end


        end

        % Value changed function: TrainAmpEditField
        function TrainAmpEditFieldValueChanged(app, event)
            app.trainAmp = app.TrainAmpEditField.Value *app.A2V;
            
        end

        % Value changed function: TrainStartEditField
        function TrainStartEditFieldValueChanged(app, event)
            app.trainDly = app.TrainStartEditField.Value;
            
        end

        % Value changed function: TrainDurationEditField
        function TrainDurationEditFieldValueChanged(app, event)
            app.trainDur = app.TrainDurationEditField.Value;
            
        end

        % Value changed function: TrainPeriodEditField
        function TrainPeriodEditFieldValueChanged(app, event)
            app.trainPrd = app.TrainPeriodEditField.Value;
            
        end

        % Value changed function: TrainWidthEditField
        function TrainWidthEditFieldValueChanged(app, event)
            app.trainWid = app.TrainWidthEditField.Value;
            
        end

        % Value changed function: PulseTrain1CheckBox
        function PulseTrain1CheckBoxValueChanged(app, event)
            app.trainOn = app.PulseTrain1CheckBox.Value;
            
        end

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)
            app.comNum = str2num(app.COMEditField.Value);
      
        end

        % Button pushed function: UpdatePreviewButton_2
        function UpdatePreviewButton_2Pushed(app, event)
            
            % overlapping spike trains
            assumedTopSpeed = 100/1000; %cm/ms
            bothTrainsOn = and(app.trainOn, app.train2On);
            distanceOverlap = and(app.trainParam == 'd',(app.train2Dly<(app.trainDly+app.trainDur)));
            timeOverlap = and(app.trainParam == 's', (app.train2Dly<(app.trainDly+app.trainDur*assumedTopSpeed)));

            %distance duration train 1
            % pulse distance duration
            analogDistDistOverlap1 = and(and(app.trainParam == 'd', app.pulseParam =='d'),or(and(app.pulseDly<(app.trainDly+app.trainDur),app.pulseDly>app.trainDly), and(app.trainDly<(app.pulseDly+app.pulseDur), app.trainDly>app.pulseDly)));
            % pulse time duration
            analogDistTimeOverlap1 = and(and(app.trainParam == 'd', app.pulseParam =='s'),or(and(app.pulseDly<(app.trainDly+app.trainDur),app.pulseDly>app.trainDly), and(app.trainDly<(app.pulseDly+app.pulseDur*assumedTopSpeed), app.trainDly>app.pulseDly)));

            % time duration train 1                       
            analogTimeDistOverlap1 = and(and(app.trainParam == 's', app.pulseParam =='d'), or(and(app.pulseDly<(app.trainDly+app.trainDur*assumedTopSpeed),app.pulseDly>app.trainDly), and(app.trainDly<(app.pulseDly+app.pulseDur), app.trainDly>app.pulseDly)));       
            analogTimeTimeOverlap1 = and(and(app.trainParam == 's', app.pulseParam =='s'), or(and(app.pulseDly<(app.trainDly+app.trainDur*assumedTopSpeed),app.pulseDly>app.trainDly), and(app.trainDly<(app.pulseDly+app.pulseDur*assumedTopSpeed), app.trainDly>app.pulseDly)));       
            analogOverlap1 = (analogDistDistOverlap1 || analogDistTimeOverlap1 || analogTimeDistOverlap1 || analogTimeTimeOverlap1);
            
            % distance duration train 2
            % pulse distance duration
            analogDistDistOverlap2 = and(and(app.train2Param == 'd', app.pulseParam =='d'),or(and(app.pulseDly<(app.train2Dly+app.train2Dur),app.pulseDly>app.train2Dly), and(app.train2Dly<(app.pulseDly+app.pulseDur), app.train2Dly>app.pulseDly)));
            % pulse time duration
            analogDistTimeOverlap2 = and(and(app.train2Param == 'd', app.pulseParam =='s'),or(and(app.pulseDly<(app.train2Dly+app.train2Dur*assumedTopSpeed),app.pulseDly>app.train2Dly), and(app.train2Dly<(app.pulseDly+app.pulseDur*assumedTopSpeed), app.train2Dly>app.pulseDly)));

            % time duration train 1                       
            analogTimeDistOverlap2 = and(and(app.train2Param == 's', app.pulseParam =='d'), or(and(app.pulseDly<(app.train2Dly+app.train2Dur),app.pulseDly>app.train2Dly), and(app.train2Dly<(app.pulseDly+app.pulseDur), app.train2Dly>app.pulseDly)));       
            analogTimeTimeOverlap2 = and(and(app.train2Param == 's', app.pulseParam =='s'), or(and(app.pulseDly<(app.train2Dly+app.train2Dur*assumedTopSpeed),app.pulseDly>app.train2Dly), and(app.train2Dly<(app.pulseDly+app.pulseDur*assumedTopSpeed), app.train2Dly>app.pulseDly)));       
            analogOverlap2 = (analogDistDistOverlap2 || analogDistTimeOverlap2 || analogTimeDistOverlap2 || analogTimeTimeOverlap2);
            
        
            % warnings that fail the update
            if and(bothTrainsOn,or(distanceOverlap, timeOverlap))
                warning("Pulse Train 2 cannot start until pulse train 1 ends");
            elseif and(xor(app.triOn, app.gausOn), or(app.trainOn,(app.pulseOn || app.train2On)))
                warning("Cannot have Ramp or Gaussian active with Train or Analog Pulse. Use Digital Pulse");
            elseif (and(app.pulseOn,app.trainOn) && analogOverlap1 && (app.pulseAmp + app.trainAmp >3.3))
                warning("Sum of concurrent pulse and train 1 amplitudes exceeds maximum output 6.6nA (3.3V)");
            elseif(and(app.pulseOn,app.train2On) && analogOverlap2 && (app.pulseAmp + app.train2Amp >3.3))
                warning("Sum of concurrent pulse and train 2 amplitudes exceeds maximum output 6.6nA (3.3V)");
            elseif(and(app.triOn, app.gausOn))
                warning("Cannot have Ramp and Gaussian active together");
            elseif(app.digOn && app.digParam == 'd' && (app.digDuration+app.digStart)>170)
                warning("Digital Pulse Cannot last longer than 170cm")
            elseif(app.pulseOn && app.pulseParam == 'd' && (app.pulseDur + app.pulseDly)>170)
                warning("Analog Pulse cannot last longer than 170cm")
            elseif(app.trainOn && app.trainParam=='d' && (app.trainDly+app.trainDur)>170)
                warning("Train cannot last longer than 170cm")
            elseif(and(app.spikeParam=='s', and(app.trainOn, app.trainWid < app.trainPrd)))
                warning("Pulse Width in Hz must be larger than Period");
            elseif(and(app.spikeParam == 'd', and(app.trainOn, app.trainWid > app.trainPrd)))
                warning("Pulse Width in cm must be smaller than Period");
            elseif(app.trainOn && and(app.trainParam==app.spikeParam, app.trainPrd == app.trainDur))
                warning("Set pulse train period to a different value than its duration");
            elseif(app.train2On && app.train2Param=='d' && (app.train2Dly+app.train2Dur)>170)
                warning("Train cannot last longer than 170cm")
            elseif(and(app.spike2Param=='s', and(app.train2On, app.train2Wid < app.train2Prd)))
                warning("Pulse Width in Hz must be larger than Period");
            elseif(and(app.spike2Param == 'd', and(app.train2On, app.train2Wid > app.train2Prd)))
                warning("Pulse Width in cm must be smaller than Period");
            elseif(app.train2On && and(app.train2Param==app.spike2Param, app.train2Prd == app.train2Dur))
                warning("Set pulse train period to a different value than its duration");
            elseif(app.triOn && (app.triDly>app.triPk || app.triPk>app.triEnd))
                warning("Triangle Pulse distances must ascend. Check inputs again.")
            elseif(app.gausOn && (app.gausLeftWid<1 || 1>app.gausRightWid))
                warning("Gaussian distances must ascend. Check inputs again.")
            else
            
                % warnings that could cause problems
                if(app.digOn && app.digParam == 's' && (app.digStart +app.digDuration/25)>170)
                    warning("Digital Pulse may outlast 170 cm")
                end
                if(app.pulseOn && app.pulseParam =='s' && (app.pulseDly+app.pulseDur/25)>170)
                    warning("Analog Pulse may outlast 170cm")
                end
                if(app.trainOn && app.trainParam=='s' && (app.trainDly+app.trainDur/25)>170)
                    warning("Train may outlast 170cm")
                end    
                if(app.train2On && app.train2Param=='s' && (app.train2Dly+app.train2Dur/25)>170)
                    warning("Train 2 may outlast 170cm")
                end    
                
                % adjust the time-based x limits
                time_xlim = max([1000, app.digDuration, app.pulseDur, app.trainDur, app.train2Dur]);
                if (app.trainParam == 'd' && app.spikeParam =='s')
                    time_xlim = max(time_xlim, app.trainDur*25);
                end
                if (app.train2Param == 'd' && app.spike2Param =='s')
                    time_xlim = max(time_xlim, app.train2Dur*25);
                end
                
                time_xlim = ceil(time_xlim/1000)*1000; % round to nearest thousand
                
                % initialize signal arrays
                y = zeros(1,181); % analog signal
                pulseTime_y =zeros(1,time_xlim+1); %time-based pulse 
                dPulse_y = zeros(1,time_xlim+1); %time-based digital pulse
                dPulse_dist = zeros(1,181); % digital signal on the top graph
                spike_y = zeros(1,time_xlim+1); % time-based spikes
                spike_y2 = zeros(1,time_xlim+1); % time-based spikes for pulse train  2
                
                % generate digital signal
                if app.digOn
                    if  strcmp(app.digParam, 'd')   
                      dPulse_dist((app.digStart+1):(app.digStart+app.digDuration)) = dPulse_dist((app.digStart+1):(app.digStart+app.digDuration)) + 1;
                    elseif  strcmp(app.digParam, 's') 
                      estimateDPulseLen = min(ceil(app.digDuration/25), 180-app.pulseDly);
                      dPulse_dist(app.digStart+(1:estimateDPulseLen)) =1;
                      dPulse_y(1:app.digDuration) = 1;
                    end
                end
                
                % analog pulse
                if app.pulseOn
                  if  strcmp(app.pulseParam, 'd')
                      y((app.pulseDly+1):(app.pulseDly+app.pulseDur)) = y((app.pulseDly+1):(app.pulseDly+app.pulseDur)) + app.pulseAmp/app.A2V;
                  elseif strcmp(app.pulseParam, 's')
                      
                      estimateDPulseLen = min(ceil(app.pulseDur/25), 180-app.pulseDly);
                      y((app.pulseDly+1):(app.pulseDly+estimateDPulseLen)) = y((app.pulseDly+1):(app.pulseDly+estimateDPulseLen)) + app.pulseAmp/app.A2V;
                      pulseTime_y(1:app.pulseDur) = app.pulseAmp/app.A2V;
                  end
                end
                
                % pulse train 1
                if app.trainOn
                    if strcmp(app.trainParam, 'd')
                        if  strcmp(app.spikeParam, 'd')
                            single_pulse = zeros(1,app.trainPrd); 
                            single_pulse(1:app.trainWid) = app.trainAmp/app.A2V;
                            all_pulses = repmat(single_pulse, 1, ceil(app.trainDur/app.trainPrd));
                            all_pulses = all_pulses(1:app.trainDur);
                            
                            train_inds = [(app.trainDly+1):(app.trainDly+app.trainDur)];
                            y(train_inds) = y(train_inds) + all_pulses;    
                        
                        elseif strcmp(app.spikeParam, 's')
        
                            prdTs = round(1/app.trainPrd*1000);
                            width = round(1/app.trainWid*1000);
                            estTime = app.trainDur*25;
                            numInTime = ceil(estTime/prdTs);
                            single_spike = zeros(1,prdTs);
                            single_spike(1:width) = app.trainAmp/app.A2V;
                            all_spikes = repmat(single_spike, 1, numInTime);
                            spike_y(1:min(time_xlim, length(all_spikes))) = all_spikes(1:min(time_xlim, length(all_spikes)));
                            
                            sample_spikes = zeros(1,app.trainDur);
                            sample_spikes(1:2:end) = app.trainAmp/app.A2V;
                            y((app.trainDly+1):(app.trainDly+app.trainDur)) = y((app.trainDly+1):(app.trainDly+app.trainDur)) + sample_spikes;


                        end
                    end        
                    if strcmp(app.trainParam, 's')
                        if  strcmp(app.spikeParam, 'd')


                            estimateSpikeWidth = app.trainWid * 25;
                            estimateSpikePrd = app.trainPrd *25;
                            estTrainDur = app.trainDur/25;
                            num_pulses = min(ceil(estTrainDur/app.trainPrd), ceil((180-app.trainDly)/app.trainPrd)); % no more than number length of lap
                            single_pulse = zeros(1,app.trainPrd); 
                            single_pulse(1:app.trainWid) = app.trainAmp/app.A2V;
                            x_pulses = repmat(single_pulse, 1, num_pulses);


                            single_spike = zeros(1,estimateSpikePrd);
                            single_spike(1:estimateSpikeWidth) = app.trainAmp/app.A2V;
                            all_spikes = repmat(single_spike, 1, num_pulses);
                            spike_y(1:min(length(all_spikes),app.trainDur)) = all_spikes(1:min(length(all_spikes),app.trainDur));             
                                                   
                            train_inds = [(app.trainDly+1):(app.trainDly+length(x_pulses))];
                            
                            % truncate spikes to end of lap
                            maxInds = find(train_inds == 180);
                            if ~isempty(maxInds)
                                train_inds = train_inds(1:maxInds);
                                x_pulses = x_pulses(1:maxInds);
                            end
                            y(train_inds) = y(train_inds) + x_pulses;    
                        
                        elseif strcmp(app.spikeParam, 's')
        
                            prdTs = round(1/app.trainPrd*1000);
                            width = round(1/app.trainWid*1000);
                            single_spike = zeros(1,prdTs);
                            single_spike(1:width) = app.trainAmp/app.A2V;
                            all_spikes = repmat(single_spike, 1, ceil(app.trainDur/app.trainPrd));
                            spike_y(1:min(length(all_spikes),app.trainDur)) = all_spikes(1:min(length(all_spikes),app.trainDur));
                            
                            estimateTrainDur = min(ceil(app.trainDur/25), (180-app.trainDly));
                            sample_spikes = zeros(1,estimateTrainDur);
                            sample_spikes(1:2:end) = app.trainAmp/app.A2V;
                            y((app.trainDly+1):(app.trainDly+estimateTrainDur)) = y((app.trainDly+1):(app.trainDly+estimateTrainDur)) + sample_spikes;


                        end
                    end        
                end
                
                
                % pulse train 2
                if app.train2On
                    if strcmp(app.train2Param, 'd')
                        if  strcmp(app.spike2Param, 'd')
                            single_pulse2 = zeros(1,app.train2Prd); 
                            single_pulse2(1:app.train2Wid) = app.train2Amp/app.A2V;
                            all_pulses2 = repmat(single_pulse2, 1, ceil(app.train2Dur/app.train2Prd));
                            all_pulses2 = all_pulses2(1:app.train2Dur);
                            
                            train_inds2 = [(app.train2Dly+1):(app.train2Dly+app.train2Dur)];
                            y(train_inds2) = y(train_inds2) + all_pulses2;    
                        
                        elseif strcmp(app.spike2Param, 's')
                            
                            prdTs2 = round(1/app.train2Prd*1000);
                            width2 = round(1/app.train2Wid*1000);
                            estTime2 = app.train2Dur*25;
                            numInTime2 = ceil(estTime2/prdTs2);
                            single_spike2 = zeros(1,prdTs2);
                            single_spike2(1:width2) = app.train2Amp/app.A2V;
                            all_spikes2 = repmat(single_spike2, 1, numInTime2);
                            spike_y2(1:min(time_xlim, length(all_spikes2))) = all_spikes2(1:min(time_xlim, length(all_spikes2)));
                            
                            sample_spikes2 = zeros(1,app.train2Dur);
                            sample_spikes2(1:2:end) = app.train2Amp/app.A2V;
                            y((app.train2Dly+1):(app.train2Dly+app.train2Dur)) = y((app.train2Dly+1):(app.train2Dly+app.train2Dur)) + sample_spikes2;
                                                                                  
                        end
                    end       
                    
                    if strcmp(app.train2Param, 's')
                        if  strcmp(app.spike2Param, 'd')

                            estimateSpikeWidth2 = app.train2Wid * 25;
                            estimateSpikePrd2 = app.train2Prd *25;
                            estTrainDur2 = app.train2Dur/25;
                            num_pulses2 = min(ceil(estTrainDur2/app.train2Prd), ceil((180-app.train2Dly)/app.train2Prd)); % no more than number length of lap
                            single_pulse2 = zeros(1,app.train2Prd); 
                            single_pulse2(1:app.train2Wid) = app.train2Amp/app.A2V;
                            x_pulses2 = repmat(single_pulse2, 1, num_pulses2);


                            single_spike2 = zeros(1,estimateSpikePrd2);
                            single_spike2(1:estimateSpikeWidth2) = app.train2Amp/app.A2V;
                            all_spikes2 = repmat(single_spike2, 1, num_pulses2);
                            spike_y2(1:min(length(all_spikes2),app.train2Dur)) = all_spikes2(1:min(length(all_spikes2),app.train2Dur));             
                                                   
                            train_inds2 = [(app.train2Dly+1):(app.train2Dly+length(x_pulses2))];
                            
                            % truncate spikes to end of lap
                            maxInds2 = find(train_inds2 == 180);
                            if ~isempty(maxInds2)
                                train_inds2 = train_inds2(1:maxInds2);
                                x_pulses2 = x_pulses2(1:maxInds2);
                            end
                            y(train_inds2) = y(train_inds2) + x_pulses2;    
                        
                        elseif strcmp(app.spike2Param, 's')
                            prdTs2 = round(1/app.train2Prd*1000);
                            width2 = round(1/app.train2Wid*1000);
                            single_spike2 = zeros(1,prdTs2);
                            single_spike2(1:width2) = app.train2Amp/app.A2V;
                            all_spikes2 = repmat(single_spike2, 1, ceil(app.train2Dur/app.train2Prd));
                            spike_y2(1:min(length(all_spikes2),app.train2Dur)) = all_spikes2(1:min(length(all_spikes2),app.train2Dur));
                            
                            estimateTrainDur2 = min(ceil(app.train2Dur/25), (180-app.train2Dly));
                            sample_spikes2 = zeros(1,estimateTrainDur2);
                            sample_spikes2(1:2:end) = app.train2Amp/app.A2V;
                            y((app.train2Dly+1):(app.train2Dly+estimateTrainDur2)) = y((app.train2Dly+1):(app.train2Dly+estimateTrainDur2)) + sample_spikes2;
                                                                                    
                        end
                    end        
                end            
                                            
                % triangle pulse
                if app.triOn
                    currTriAmp = app.triAmp/app.A2V;
                    triSlopeUp = currTriAmp/(app.triPk-app.triDly);
                    triSlopeDown = currTriAmp/(app.triPk-app.triEnd);
                    triPulseUp = ((0:(app.triPk-app.triDly-1)))*triSlopeUp;
                    triPulseDown = ((app.triPk:app.triEnd) - app.triPk)*triSlopeDown+currTriAmp;
                    
                    triPulse = [triPulseUp,triPulseDown];
                    triInds = (app.triDly+1):(app.triEnd+1);
                    %fprintf("size UP:%g, size down: %g, indSize:%g \n", length(triPulseUp), length(triPulseDown), length(triInds))
                   
                    
                    y(triInds) = y(triInds)+triPulse;
                end
                
                % gaussian
                if app.gausOn
                    currGausAmp = app.gausAmp/app.A2V;
                    gausInds = cumsum(ones(1,181));
                    % up gaussian
                    upInds = 1:(app.gausCent+1);
                    y(upInds) = currGausAmp*exp(-log(2)*((gausInds(upInds)-app.gausCent)/app.gausLeftWid).^2);
                    
                    % down gaussian
                    downInds = (app.gausCent+1):170;
                    y(downInds) = currGausAmp*exp(-log(2)*((gausInds(downInds)-app.gausCent)/app.gausRightWid).^2);
                    %fprintf ("Amp: %g, Cent: %g, L:%g, R:%g", app.gausAmp, app.gausCent, app.gausLeftWid, app.gausRightWid)
                end
                
                cla(app.UIAxes)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, y);
                plot(app.UIAxes, dPulse_dist);
                legend(app.UIAxes, {'Analog Out','Digital Out'},'Location', 'best', 'FontSize',6)
                hold(app.UIAxes, 'off')
                         
                cla(app.UIAxes2)
                hold(app.UIAxes2, 'on')
                xlim(app.UIAxes2, [0 time_xlim])
                plot(app.UIAxes2, spike_y)
                plot(app.UIAxes2, spike_y2)
                plot(app.UIAxes2, dPulse_y)
                plot(app.UIAxes2, pulseTime_y)
                legend(app.UIAxes2, {'Spike Train 1', 'Spike Train 2', 'Digital Pulse','Analog Pulse'},'Location', 'best','FontSize',6)
                hold(app.UIAxes2, 'off')
            end
        end

        % Value changed function: DigitalPulseCheckBox
        function DigitalPulseCheckBoxValueChanged(app, event)
            app.digOn = app.DigitalPulseCheckBox.Value;
            
        end

        % Value changed function: DigitalStartEditField
        function DigitalStartEditFieldValueChanged(app, event)
            app.digStart = app.DigitalStartEditField.Value;
            
        end

        % Value changed function: DigitalDurationEditField
        function DigitalDurationEditFieldValueChanged(app, event)
            app.digDuration = app.DigitalDurationEditField.Value;
            
        end

        % Value changed function: DigitalDurationDropDown
        function DigitalDurationDropDownValueChanged(app, event)
            dval = app.DigitalDurationDropDown.Value;
            if strcmp(dval, 'ms')
                app.digParam = 's';
            else
                app.digParam = 'd';
            end
        end

        % Value changed function: PulseDropDown
        function PulseDropDownValueChanged(app, event)
            pval = app.PulseDropDown.Value;
            if strcmp(pval, 'ms')
                app.pulseParam = 's';
            else
                app.pulseParam = 'd';
            end
        end

        % Value changed function: TrainParamDropDown
        function TrainParamDropDownValueChanged(app, event)
            tparam = app.TrainParamDropDown.Value;
            if strcmp(tparam, 'ms')
                app.trainParam = 's';
            else
                app.trainParam = 'd';
            end
        end

        % Value changed function: SpikeParamDropDown
        function SpikeParamDropDownValueChanged(app, event)
            sval = app.SpikeParamDropDown.Value;
            if strcmp(sval, 'Hz')
                app.spikeParam = 's';
            else
                app.spikeParam = 'd';
            end
        end

        % Value changed function: TrainAmpEditField_2
        function TrainAmpEditField_2ValueChanged(app, event)
            app.train2Amp = app.TrainAmpEditField_2.Value * app.A2V;
            
        end

        % Value changed function: TrainStartEditField_2
        function TrainStartEditField_2ValueChanged(app, event)
            app.train2Dly = app.TrainStartEditField_2.Value;
            
        end

        % Value changed function: TrainDurationEditField_2
        function TrainDurationEditField_2ValueChanged(app, event)
            app.train2Dur= app.TrainDurationEditField_2.Value;
            
        end

        % Value changed function: TrainPeriodEditField_2
        function TrainPeriodEditField_2ValueChanged(app, event)
            app.train2Prd = app.TrainPeriodEditField_2.Value;
            
        end

        % Value changed function: TrainWidthEditField_2
        function TrainWidthEditField_2ValueChanged(app, event)
            app.train2Wid = app.TrainWidthEditField_2.Value;
            
        end

        % Value changed function: TrainParamDropDown_2
        function TrainParamDropDown_2ValueChanged(app, event)
            tparam2 = app.TrainParamDropDown_2.Value;
            if strcmp(tparam2, 'ms')
                app.train2Param = 's';
            else
                app.train2Param = 'd';
            end
        end

        % Value changed function: SpikeParamDropDown_2
        function SpikeParamDropDown_2ValueChanged(app, event)
            sval2 = app.SpikeParamDropDown_2.Value;
            if strcmp(sval2, 'Hz')
                app.spike2Param = 's';
            else
                app.spike2Param = 'd';
            end
        end

        % Value changed function: PulseTrain2CheckBox
        function PulseTrain2CheckBoxValueChanged(app, event)
            app.train2On = app.PulseTrain2CheckBox.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {489, 489};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {377, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DigitalPulseTab = uitab(app.TabGroup);
            app.DigitalPulseTab.Title = 'Digital Pulse';
            app.StartcmEditField_4Label_2 = uilabel(app.DigitalPulseTab);
            app.StartcmEditField_4Label_2.Text = 'Start (cm)';
            app.DigitalStartEditField = uieditfield(app.DigitalPulseTab, 'numeric');
            app.DigitalStartEditField.Limits = [0 170];
            app.DigitalStartEditField.ValueDisplayFormat = '%3.0f';
            app.DigitalStartEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalStartEditFieldValueChanged, true);
            app.DurationEditField_2Label = uilabel(app.DigitalPulseTab);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DigitalDurationEditField = uieditfield(app.DigitalPulseTab, 'numeric');
            app.DigitalDurationEditField.Limits = [0 9999];
            app.DigitalDurationEditField.ValueDisplayFormat = '%3.0f';
            app.DigitalDurationEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalDurationEditFieldValueChanged, true);
            app.DigitalDurationDropDown = uidropdown(app.DigitalPulseTab);
            app.DigitalDurationDropDown.Items = {'ms', 'cm'};
            app.DigitalDurationDropDown.ValueChangedFcn = createCallbackFcn(app, @DigitalDurationDropDownValueChanged, true);
            app.DigitalDurationDropDown.Value = 'ms';
            app.PulseTab = uitab(app.TabGroup);
            app.PulseTab.Title = 'Pulse';
            app.AmplitudenAEditFieldLabel = uilabel(app.PulseTab);
            app.AmplitudenAEditFieldLabel.Text = 'Amplitude (nA)';
            app.PulseAmpEditField = uieditfield(app.PulseTab, 'numeric');
            app.PulseAmpEditField.Limits = [0 6.6];
            app.PulseAmpEditField.ValueDisplayFormat = '%1.2f';
            app.PulseAmpEditField.ValueChangedFcn = createCallbackFcn(app, @PulseAmpEditFieldValueChanged, true);
            app.StartcmEditFieldLabel = uilabel(app.PulseTab);
            app.StartcmEditFieldLabel.Text = 'Start (cm)';
            app.PulseStartEditField = uieditfield(app.PulseTab, 'numeric');
            app.PulseStartEditField.Limits = [0 170];
            app.PulseStartEditField.ValueDisplayFormat = '%3.0f';
            app.PulseStartEditField.ValueChangedFcn = createCallbackFcn(app, @PulseStartEditFieldValueChanged, true);
            app.DurationEditFieldLabel = uilabel(app.PulseTab);
            app.DurationEditFieldLabel.Text = 'Duration ';
            app.PulseDurationEditField = uieditfield(app.PulseTab, 'numeric');
            app.PulseDurationEditField.Limits = [0 9999];
            app.PulseDurationEditField.ValueDisplayFormat = '%4.0f';
            app.PulseDurationEditField.ValueChangedFcn = createCallbackFcn(app, @PulseDurationEditFieldValueChanged, true);
            app.PulseDropDown = uidropdown(app.PulseTab);
            app.PulseDropDown.Items = {'ms', 'cm'};
            app.PulseDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseDropDownValueChanged, true);
            app.PulseDropDown.Value = 'ms';
            app.PulseTrain1Tab = uitab(app.TabGroup);
            app.PulseTrain1Tab.Title = 'Pulse Train 1';
            app.AmplitudenAEditField_2Label = uilabel(app.PulseTrain1Tab);
            app.AmplitudenAEditField_2Label.Text = 'Amplitude (nA)';
            app.TrainAmpEditField = uieditfield(app.PulseTrain1Tab, 'numeric');
            app.TrainAmpEditField.Limits = [0 6.6];
            app.TrainAmpEditField.ValueDisplayFormat = '%1.2f';
            app.TrainAmpEditField.ValueChangedFcn = createCallbackFcn(app, @TrainAmpEditFieldValueChanged, true);
            app.StartcmEditField_4Label = uilabel(app.PulseTrain1Tab);
            app.StartcmEditField_4Label.Text = 'Start (cm)';
            app.TrainStartEditField = uieditfield(app.PulseTrain1Tab, 'numeric');
            app.TrainStartEditField.Limits = [0 170];
            app.TrainStartEditField.ValueDisplayFormat = '%3.0f';
            app.TrainStartEditField.ValueChangedFcn = createCallbackFcn(app, @TrainStartEditFieldValueChanged, true);
            app.DurationEditField_3Label = uilabel(app.PulseTrain1Tab);
            app.DurationEditField_3Label.Text = 'Duration';
            app.TrainDurationEditField = uieditfield(app.PulseTrain1Tab, 'numeric');
            app.TrainDurationEditField.Limits = [0 9999];
            app.TrainDurationEditField.ValueDisplayFormat = '%3.0f';
            app.TrainDurationEditField.ValueChangedFcn = createCallbackFcn(app, @TrainDurationEditFieldValueChanged, true);
            app.PeriodEditFieldLabel = uilabel(app.PulseTrain1Tab);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.TrainPeriodEditField = uieditfield(app.PulseTrain1Tab, 'numeric');
            app.TrainPeriodEditField.Limits = [0 500];
            app.TrainPeriodEditField.ValueDisplayFormat = '%.0f';
            app.TrainPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @TrainPeriodEditFieldValueChanged, true);
            app.PulseWidthEditFieldLabel = uilabel(app.PulseTrain1Tab);
            app.PulseWidthEditFieldLabel.Text = 'Pulse Width';
            app.TrainWidthEditField = uieditfield(app.PulseTrain1Tab, 'numeric');
            app.TrainWidthEditField.Limits = [0 999];
            app.TrainWidthEditField.ValueDisplayFormat = '%.0f';
            app.TrainWidthEditField.ValueChangedFcn = createCallbackFcn(app, @TrainWidthEditFieldValueChanged, true);
            app.TrainParamDropDown = uidropdown(app.PulseTrain1Tab);
            app.TrainParamDropDown.Items = {'ms', 'cm'};
            app.TrainParamDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainParamDropDownValueChanged, true);
            app.TrainParamDropDown.Value = 'ms';
            app.SpikeParamDropDown = uidropdown(app.PulseTrain1Tab);
            app.SpikeParamDropDown.Items = {'Hz', 'cm'};
            app.SpikeParamDropDown.ValueChangedFcn = createCallbackFcn(app, @SpikeParamDropDownValueChanged, true);
            app.SpikeParamDropDown.Value = 'Hz';
            app.PulseTrain2Tab = uitab(app.TabGroup);
            app.PulseTrain2Tab.Title = 'Pulse Train 2';
            app.AmplitudenAEditField_2Label_2 = uilabel(app.PulseTrain2Tab);
            app.AmplitudenAEditField_2Label_2.Text = 'Amplitude (nA)';
            app.TrainAmpEditField_2 = uieditfield(app.PulseTrain2Tab, 'numeric');
            app.TrainAmpEditField_2.Limits = [0 6.6];
            app.TrainAmpEditField_2.ValueDisplayFormat = '%1.2f';
            app.TrainAmpEditField_2.ValueChangedFcn = createCallbackFcn(app, @TrainAmpEditField_2ValueChanged, true);
            app.StartcmEditField_4Label_3 = uilabel(app.PulseTrain2Tab);
            app.StartcmEditField_4Label_3.Text = 'Start (cm)';
            app.TrainStartEditField_2 = uieditfield(app.PulseTrain2Tab, 'numeric');
            app.TrainStartEditField_2.Limits = [0 170];
            app.TrainStartEditField_2.ValueDisplayFormat = '%3.0f';
            app.TrainStartEditField_2.ValueChangedFcn = createCallbackFcn(app, @TrainStartEditField_2ValueChanged, true);
            app.DurationEditField_3Label_2 = uilabel(app.PulseTrain2Tab);
            app.DurationEditField_3Label_2.Text = 'Duration';
            app.TrainDurationEditField_2 = uieditfield(app.PulseTrain2Tab, 'numeric');
            app.TrainDurationEditField_2.Limits = [0 9999];
            app.TrainDurationEditField_2.ValueDisplayFormat = '%3.0f';
            app.TrainDurationEditField_2.ValueChangedFcn = createCallbackFcn(app, @TrainDurationEditField_2ValueChanged, true);
            app.PeriodEditFieldLabel_2 = uilabel(app.PulseTrain2Tab);
            app.PeriodEditFieldLabel_2.Text = 'Period';
            app.TrainPeriodEditField_2 = uieditfield(app.PulseTrain2Tab, 'numeric');
            app.TrainPeriodEditField_2.Limits = [0 500];
            app.TrainPeriodEditField_2.ValueDisplayFormat = '%.0f';
            app.TrainPeriodEditField_2.ValueChangedFcn = createCallbackFcn(app, @TrainPeriodEditField_2ValueChanged, true);
            app.PulseWidthEditFieldLabel_2 = uilabel(app.PulseTrain2Tab);
            app.PulseWidthEditFieldLabel_2.Text = 'Pulse Width';
            app.TrainWidthEditField_2 = uieditfield(app.PulseTrain2Tab, 'numeric');
            app.TrainWidthEditField_2.Limits = [0 999];
            app.TrainWidthEditField_2.ValueDisplayFormat = '%.0f';
            app.TrainWidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @TrainWidthEditField_2ValueChanged, true);
            app.TrainParamDropDown_2 = uidropdown(app.PulseTrain2Tab);
            app.TrainParamDropDown_2.Items = {'ms', 'cm'};
            app.TrainParamDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TrainParamDropDown_2ValueChanged, true);
            app.TrainParamDropDown_2.Value = 'ms';
            app.SpikeParamDropDown_2 = uidropdown(app.PulseTrain2Tab);
            app.SpikeParamDropDown_2.Items = {'Hz', 'cm'};
            app.SpikeParamDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SpikeParamDropDown_2ValueChanged, true);
            app.SpikeParamDropDown_2.Value = 'Hz';
            app.TriangleTab = uitab(app.TabGroup);
            app.TriangleTab.Title = 'Triangle';
            app.AmplitudenAEditField_3Label = uilabel(app.TriangleTab);
            app.AmplitudenAEditField_3Label.Text = 'Amplitude (nA)';
            app.AmplitudenAEditField_3 = uieditfield(app.TriangleTab, 'numeric');
            app.AmplitudenAEditField_3.Limits = [0 6.6];
            app.AmplitudenAEditField_3.ValueDisplayFormat = '%1.2f';
            app.AmplitudenAEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudenAEditField_3ValueChanged, true);
            app.StartcmEditField_5Label = uilabel(app.TriangleTab);
            app.StartcmEditField_5Label.Text = 'Start (cm)';
            app.StartcmEditField_3 = uieditfield(app.TriangleTab, 'numeric');
            app.StartcmEditField_3.Limits = [0 170];
            app.StartcmEditField_3.ValueDisplayFormat = '%3.0f';
            app.StartcmEditField_3.ValueChangedFcn = createCallbackFcn(app, @StartcmEditField_3ValueChanged, true);
            app.PeakcmEditFieldLabel = uilabel(app.TriangleTab);
            app.PeakcmEditFieldLabel.Text = 'Peak (cm)';
            app.PeakcmEditField = uieditfield(app.TriangleTab, 'numeric');
            app.PeakcmEditField.Limits = [0 170];
            app.PeakcmEditField.ValueDisplayFormat = '%3.0f';
            app.PeakcmEditField.ValueChangedFcn = createCallbackFcn(app, @PeakcmEditFieldValueChanged, true);
            app.EndcmLabel = uilabel(app.TriangleTab);
            app.EndcmLabel.Text = 'End (cm)';
            app.EndcmEditField = uieditfield(app.TriangleTab, 'numeric');
            app.EndcmEditField.Limits = [0 170];
            app.EndcmEditField.ValueDisplayFormat = '%3.0f';
            app.EndcmEditField.ValueChangedFcn = createCallbackFcn(app, @EndcmEditFieldValueChanged, true);
            app.GaussianTab = uitab(app.TabGroup);
            app.GaussianTab.Title = 'Gaussian';
            app.AmplitudenAEditField_4Label = uilabel(app.GaussianTab);
            app.AmplitudenAEditField_4Label.Text = 'Amplitude (nA)';
            app.AmplitudenAEditField_4 = uieditfield(app.GaussianTab, 'numeric');
            app.AmplitudenAEditField_4.Limits = [0 6.6];
            app.AmplitudenAEditField_4.ValueDisplayFormat = '%1.2f';
            app.AmplitudenAEditField_4.ValueChangedFcn = createCallbackFcn(app, @AmplitudenAEditField_4ValueChanged, true);
            app.LeftWidthAtHalfMaxEditFieldLabel = uilabel(app.GaussianTab);
            app.LeftWidthAtHalfMaxEditFieldLabel.Text = 'Left Width At Half Max';
            app.LeftWidthAtHalfMaxEditField = uieditfield(app.GaussianTab, 'numeric');
            app.LeftWidthAtHalfMaxEditField.Limits = [0 170];
            app.LeftWidthAtHalfMaxEditField.ValueDisplayFormat = '%3.0f';
            app.LeftWidthAtHalfMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LeftWidthAtHalfMaxEditFieldValueChanged, true);
            app.CenterEditFieldLabel = uilabel(app.GaussianTab);
            app.CenterEditFieldLabel.Text = 'Center';
            app.CenterEditField = uieditfield(app.GaussianTab, 'numeric');
            app.CenterEditField.Limits = [0 170];
            app.CenterEditField.ValueDisplayFormat = '%3.0f';
            app.CenterEditField.ValueChangedFcn = createCallbackFcn(app, @CenterEditFieldValueChanged, true);
            app.RightWidthAtHalfMaxLabel = uilabel(app.GaussianTab);
            app.RightWidthAtHalfMaxLabel.Text = 'Right Width At Half Max';
            app.RightWidthAtHalfMaxEditField = uieditfield(app.GaussianTab, 'numeric');
            app.RightWidthAtHalfMaxEditField.Limits = [0 170];
            app.RightWidthAtHalfMaxEditField.ValueDisplayFormat = '%3.0f';
            app.RightWidthAtHalfMaxEditField.ValueChangedFcn = createCallbackFcn(app, @RightWidthAtHalfMaxEditFieldValueChanged, true);
            app.PulseCheckBox = uicheckbox(app.LeftPanel);
            app.PulseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseCheckBoxValueChanged, true);
            app.PulseCheckBox.Text = 'Pulse';
            app.PulseTrain1CheckBox = uicheckbox(app.LeftPanel);
            app.PulseTrain1CheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseTrain1CheckBoxValueChanged, true);
            app.PulseTrain1CheckBox.Text = 'Pulse Train 1';
            app.GaussianCheckBox = uicheckbox(app.LeftPanel);
            app.GaussianCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianCheckBoxValueChanged, true);
            app.GaussianCheckBox.Text = 'Gaussian';
            app.TriangleCheckBox = uicheckbox(app.LeftPanel);
            app.TriangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriangleCheckBoxValueChanged, true);
            app.TriangleCheckBox.Text = 'Triangle';
            app.SessionSwitchLabel = uilabel(app.LeftPanel);
            app.SessionSwitchLabel.Text = 'Session';
            app.SessionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SessionSwitch.Items = {'Clear', 'On'};
            app.SessionSwitch.ValueChangedFcn = createCallbackFcn(app, @SessionSwitchValueChanged, true);
            app.SessionSwitch.Value = 'Clear';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.COMEditFieldLabel = uilabel(app.LeftPanel);
            app.COMEditFieldLabel.Text = 'COM #';
            app.COMEditField = uieditfield(app.LeftPanel, 'text');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = '4';
            app.DigitalPulseCheckBox = uicheckbox(app.LeftPanel);
            app.DigitalPulseCheckBox.ValueChangedFcn = createCallbackFcn(app, @DigitalPulseCheckBoxValueChanged, true);
            app.DigitalPulseCheckBox.Text = 'Digital Pulse';
            app.PulseTrain2CheckBox = uicheckbox(app.LeftPanel);
            app.PulseTrain2CheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseTrain2CheckBoxValueChanged, true);
            app.PulseTrain2CheckBox.Text = 'Pulse Train 2';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Stimulus Preview                                            ')
            xlabel(app.UIAxes, 'Distance (cm)')
            ylabel(app.UIAxes, 'Amplitude (nA)')
            app.UIAxes.XLim = [0 180];
            app.UIAxes.YLim = [0 6.6];
            app.UIAxes.XTick = [0 20 40 60 80 100 120 140 160 180];
            app.UpdatePreviewButton_2 = uibutton(app.RightPanel, 'push');
            app.UpdatePreviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButton_2Pushed, true);
            app.UpdatePreviewButton_2.Text = 'Update Preview';
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Time-Based Stimuli                                       ')
            xlabel(app.UIAxes2, 'Time (ms)')
            ylabel(app.UIAxes2, 'Amplitude (nA)')
            app.UIAxes2.XLim = [0 1000];
            app.UIAxes2.YLim = [0 6.6];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controllable_Fgen_v13_TwoTrainsFaster

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end