classdef Controllable_Fgen_v20_Delays < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        DigitalPulseTab               matlab.ui.container.Tab
        StartcmEditField_4Label_2     matlab.ui.control.Label
        DigitalStartEditField         matlab.ui.control.NumericEditField
        DurationEditField_2Label      matlab.ui.control.Label
        DigitalDurationEditField      matlab.ui.control.NumericEditField
        DigitalDurationDropDown       matlab.ui.control.DropDown
        DelaymsLabel                  matlab.ui.control.Label
        DigitalDelayEditField         matlab.ui.control.NumericEditField
        PulseTab                      matlab.ui.container.Tab
        AmplitudenAEditFieldLabel     matlab.ui.control.Label
        PulseAmpEditField             matlab.ui.control.NumericEditField
        StartcmEditFieldLabel         matlab.ui.control.Label
        PulseStartEditField           matlab.ui.control.NumericEditField
        DurationEditFieldLabel        matlab.ui.control.Label
        PulseDurationEditField        matlab.ui.control.NumericEditField
        PulseDropDown                 matlab.ui.control.DropDown
        DelaymsLabel_2                matlab.ui.control.Label
        PulseDelayEditField           matlab.ui.control.NumericEditField
        PulseTrainTab                 matlab.ui.container.Tab
        AmplitudenAEditField_2Label   matlab.ui.control.Label
        TrainAmpEditField             matlab.ui.control.NumericEditField
        StartcmEditField_4Label       matlab.ui.control.Label
        TrainStartEditField           matlab.ui.control.NumericEditField
        DurationEditField_3Label      matlab.ui.control.Label
        TrainDurationEditField        matlab.ui.control.NumericEditField
        PeriodEditFieldLabel          matlab.ui.control.Label
        TrainPeriodEditField          matlab.ui.control.NumericEditField
        PulseWidthEditFieldLabel      matlab.ui.control.Label
        TrainWidthEditField           matlab.ui.control.NumericEditField
        TrainParamDropDown            matlab.ui.control.DropDown
        SpikeParamDropDown            matlab.ui.control.DropDown
        DelaymsLabel_3                matlab.ui.control.Label
        TrainDelayEditField           matlab.ui.control.NumericEditField
        TriangleTab                   matlab.ui.container.Tab
        AmplitudenAEditField_3Label   matlab.ui.control.Label
        AmplitudenAEditField_3        matlab.ui.control.NumericEditField
        StartcmEditField_5Label       matlab.ui.control.Label
        StartcmEditField_3            matlab.ui.control.NumericEditField
        PeakcmEditFieldLabel          matlab.ui.control.Label
        PeakcmEditField               matlab.ui.control.NumericEditField
        EndcmLabel                    matlab.ui.control.Label
        EndcmEditField                matlab.ui.control.NumericEditField
        GaussianTab                   matlab.ui.container.Tab
        AmplitudenAEditField_4Label   matlab.ui.control.Label
        AmplitudenAEditField_4        matlab.ui.control.NumericEditField
        LeftWidthAtHalfMaxEditFieldLabel  matlab.ui.control.Label
        LeftWidthAtHalfMaxEditField   matlab.ui.control.NumericEditField
        CenterEditFieldLabel          matlab.ui.control.Label
        CenterEditField               matlab.ui.control.NumericEditField
        RightWidthAtHalfMaxLabel      matlab.ui.control.Label
        RightWidthAtHalfMaxEditField  matlab.ui.control.NumericEditField
        PulseCheckBox                 matlab.ui.control.CheckBox
        PulseTrainCheckBox            matlab.ui.control.CheckBox
        GaussianCheckBox              matlab.ui.control.CheckBox
        TriangleCheckBox              matlab.ui.control.CheckBox
        SessionSwitchLabel            matlab.ui.control.Label
        SessionSwitch                 matlab.ui.control.Switch
        UpdateButton                  matlab.ui.control.Button
        COMEditFieldLabel             matlab.ui.control.Label
        COMEditField                  matlab.ui.control.EditField
        DigitalPulseCheckBox          matlab.ui.control.CheckBox
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        UpdatePreviewButton_2         matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)        
        
        %digital Pulse Params
        digStart = 0;
        digDuration = 0;
        digParam = 's';
        digOn = 0;
        digDly = 0;
        
        % pulse params 
        pulseAmp =0; % amplitude of pulse V
        pulseDur = 0;
        pulseStart = 0;
        pulseOn = 0;
        pulseParam = 's'
        pulseDly = 0; % ms
        
        %pulse train params
        trainAmp =0;
        trainDur = 0;
        trainStart = 0;
        trainPrd = 0;
        trainWid = 0;
        trainOn = 0; 
        trainParam = 's';
        spikeParam = 's';
        trainDly = 0;
        
        %triangle pulse params 
        triAmp = 0;
        triPk = 0;
        triStart = 0;
        triOn = 0;
        triEnd = 0;
        triDur = 0;
        
        %gaussian params
        gausAmp = 0;
        gausCent = 0;
        gausLeftWid = 0;
        gausRightWid = 0;
        gausOn = 0;  
        
        
        SerialID
        comNum = 4;
        
        A2V = 0.5;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SessionSwitch
        function SessionSwitchValueChanged(app, event)
            isStartOn  = app.SessionSwitch.Value;
            comStr = sprintf("COM%g", app.comNum);
            switch isStartOn 
                case 'On'
                    coms = instrfind;
                    if ~isempty(coms) 
                        fclose(coms);
                    end
                    
                    app.SerialID = serial(comStr,'BaudRate',9600);
                    fopen(app.SerialID);
%                     clearstr = '<dp0,0.00,000,000!t0,0.00,000,000,00.000,00.000!^0,0.00,000,000,000!g0,0,0,0,0!>';
%                     for char = clearstr
%                         fwrite(app.SerialID, char)
%                     end
                case 'Clear'
                    fclose(app.SerialID);
                    
            end
        end

        % Value changed function: PulseAmpEditField
        function PulseAmpEditFieldValueChanged(app, event)
            
            app.pulseAmp = app.PulseAmpEditField.Value * app.A2V;
            
        end

        % Value changed function: PulseStartEditField
        function PulseStartEditFieldValueChanged(app, event)
            app.pulseStart = app.PulseStartEditField.Value;
            
        end

        % Value changed function: PulseDurationEditField
        function PulseDurationEditFieldValueChanged(app, event)
            app.pulseDur = app.PulseDurationEditField.Value;
            
        end

        % Value changed function: PulseCheckBox
        function PulseCheckBoxValueChanged(app, event)
            app.pulseOn = app.PulseCheckBox.Value;
            
        end

        % Value changed function: AmplitudenAEditField_3
        function AmplitudenAEditField_3ValueChanged(app, event)
            app.triAmp = app.AmplitudenAEditField_3.Value * app.A2V;
            
        end

        % Value changed function: StartcmEditField_3
        function StartcmEditField_3ValueChanged(app, event)
            app.triStart = app.StartcmEditField_3.Value;
            
        end

        % Value changed function: PeakcmEditField
        function PeakcmEditFieldValueChanged(app, event)
            app.triPk = app.PeakcmEditField.Value;
            
        end

        % Value changed function: EndcmEditField
        function EndcmEditFieldValueChanged(app, event)
            app.triEnd = app.EndcmEditField.Value;
            
        end

        % Value changed function: TriangleCheckBox
        function TriangleCheckBoxValueChanged(app, event)
            app.triOn = app.TriangleCheckBox.Value;
        end

        % Value changed function: AmplitudenAEditField_4
        function AmplitudenAEditField_4ValueChanged(app, event)
            app.gausAmp = app.AmplitudenAEditField_4.Value * app.A2V;
           
        end

        % Value changed function: CenterEditField
        function CenterEditFieldValueChanged(app, event)
            app.gausCent = app.CenterEditField.Value;
            % make sure left and right are up to date
            app.gausLeftWid = app.CenterEditField.Value - app.LeftWidthAtHalfMaxEditField.Value;
            app.gausRightWid = app.RightWidthAtHalfMaxEditField.Value - app.CenterEditField.Value;
        end

        % Value changed function: LeftWidthAtHalfMaxEditField
        function LeftWidthAtHalfMaxEditFieldValueChanged(app, event)
            lwhm = app.LeftWidthAtHalfMaxEditField.Value;
            app.gausLeftWid = app.CenterEditField.Value - lwhm;
        end

        % Value changed function: RightWidthAtHalfMaxEditField
        function RightWidthAtHalfMaxEditFieldValueChanged(app, event)
            rwhm = app.RightWidthAtHalfMaxEditField.Value;
            app.gausRightWid = rwhm - app.CenterEditField.Value;
            
        end

        % Value changed function: GaussianCheckBox
        function GaussianCheckBoxValueChanged(app, event)
            app.gausOn = app.GaussianCheckBox.Value;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % sending direction string to teensy
    
            % overlapping stimuli
            v = 40/1000; %cm/ms Assumed Speed
            
            % convert delays to approximate distance
            digDly_cm = ceil(app.digDly*v);
            pulseDly_cm = ceil(app.pulseDly*v);
            trainDly_cm = ceil(app.trainDly*v);

            % instances where the start of a stim begins before another ends
            % assumes worst case with respect to delays causing overlap
            % train distance duration
            trainDistOverlap = and(app.trainParam == 'd', and((app.pulseStart<(app.trainStart + app.trainDur+app.trainDly*v)), app.pulseStart+app.pulseDly*v>=app.trainStart));
            pulseDistOverlap = and(app.pulseParam == 'd', and((app.trainStart<(app.pulseStart+app.pulseDur+app.pulseDly*v)), app.trainStart+app.trainDly*v>=app.pulseStart));
            
            % potential overlap
            trainTimeOverlap = and(app.trainParam =='s', and((app.pulseStart<(app.trainStart+app.trainDur*v+app.trainDly*v)), app.pulseStart+app.pulseDly*v>=app.trainStart));
            pulseTimeOverlap = and(app.pulseParam =='s', and((app.trainStart<(app.pulseStart+app.pulseDur*v+app.pulseDly*v)), app.trainStart+app.trainDly*v>=app.pulseStart));
             
            potentialOverlap = (trainTimeOverlap|| pulseTimeOverlap || trainDistOverlap || pulseDistOverlap);
                           
            % warnings that fail the update
            if and(xor(app.triOn, app.gausOn), or(app.trainOn,app.pulseOn))
                warning("Cannot have Ramp or Gaussian active with Train or Analog Pulse. Use Digital Pulse");
            elseif(and(app.triOn, app.gausOn))
                warning("Cannot have Ramp and Gaussian active together");
            elseif(app.digOn && app.digParam == 'd' && (app.digDuration+app.digStart)>170)
                warning("Digital Pulse Cannot last longer than 170cm")
            elseif(app.pulseOn && app.pulseParam == 'd' && (app.pulseDur + app.pulseStart)>170)
                warning("Analog Pulse cannot last longer than 170cm")
            elseif(app.trainOn && app.trainParam=='d' && (app.trainStart+app.trainDur)>170)
                warning("Train cannot last longer than 170cm")
            elseif(app.trainOn && or(app.trainWid==0, app.trainPrd==0))
                warning("Pulse Train cannot be active with width or period of zero")
            elseif(and(app.spikeParam=='s', and(app.trainOn, app.trainWid < app.trainPrd)))
                warning("Pulse Width in Hz must be larger than Period");
            elseif(and(app.spikeParam == 'd', and(app.trainOn, app.trainWid > app.trainPrd)))
                warning("Pulse Width in cm must be smaller than Period");
            elseif(app.trainOn && and(app.trainParam==app.spikeParam, app.trainPrd == app.trainDur))
                warning("Set pulse train period to a different value than its duration");
            elseif(app.trainOn && app.trainParam=='d' && app.spikeParam=='d' && app.trainPrd >= app.trainDur)
                warning("Pulse train period in cm must be less than or equal to the duration in cm")
            elseif(app.triOn && or(app.triStart>app.triPk, app.triPk>app.triEnd))
                warning("Triangle Pulse distances must ascend. Check inputs again.")
            elseif(app.gausOn && (app.gausLeftWid<1 || 1>app.gausRightWid))
                warning("Gaussian distances must ascend. Check inputs again.")
            elseif(app.digOn && (app.digStart+digDly_cm>=170))
                warning("Digital pulse delay is too long. Move start position back or reduce delay.")
            elseif(app.pulseOn && (app.pulseStart+pulseDly_cm>=170))
                warning("Analog pulse delay is too long. Move start position back or reduce delay.")
            elseif(app.trainOn && (app.trainStart+trainDly_cm>=170))
                warning("Pulse train delay is too long. Move start position back or reduce delay.")
            else
            
                % warnings that could cause problems
                if(app.digOn && app.digParam == 's' && (app.digStart +app.digDuration/25 + app.digDly*v)>170)
                    warning("Digital Pulse may outlast 170 cm")
                end
                if(app.pulseOn && app.pulseParam =='s' && (app.pulseStart+app.pulseDur/25+app.digDly*v)>170)
                    warning("Analog Pulse may outlast 170cm")
                end
                if(app.trainOn && app.trainParam=='s' && (app.trainStart+app.trainDur/25+app.digDly*v)>170)
                    warning("Train may outlast 170cm")
                end    
                if(app.pulseOn && app.trainOn && potentialOverlap && ((app.pulseAmp + app.trainAmp) >3.3))
                    warning("Potentially overlapping pulse and train may exceed 6.6nA")
                end
                if(app.digOn && app.digParam=='d' && (app.digStart+app.digDuration+app.digDly*v)>170)
                    warning("Digital pulse may be delayed past 170cm")
                    digDistFlag = true; % flag this stnuli for possibly runnning long
                end
                if(app.pulseOn && app.pulseParam=='d' && (app.pulseStart+app.pulseDur+app.pulseDly*v)>170)
                    warning("Analog Pulse may be delayed past 170cm")
                    pulseDistFlag = true;
                end 
                if(app.trainOn && app.trainParam=='d' && (app.trainStart+app.trainDur+app.trainDly*v)>170)
                    warning("Train may be delayed past 170cm")
                    trainDistFlag = true;
                end  
                if(app.trainOn && app.trainParam=='s' && (app.trainStart+app.trainDur*v+app.trainDly*v)>170)
                    warning("Train may be delayed past 170cm")
                    trainDistFlag = true;
                end 


                % show preview
                UpdatePreviewButton_2Pushed(app, event)
            
                % make sure teensy not active
                try 
                    fprintf('Flushing \n')
                    flushinput(app.SerialID);
                catch ME
                    %pass    
                end
                try
                    flushinput(app.SerialID);
                    isReadyToReceive = fread(app.SerialID,1, 'uint8')==255;
                catch ME
                    warning('Something went wrong on Serial read, try restarting session');
                    return;
                end


                % send time/dist/velo parameter


                % send parameters as string
                app.triDur = app.triEnd-app.triStart;
                
                digStr = sprintf('b%1.0f,%03.0f,%s,%04.0f,%04.0f!', app.digOn, app.digStart, app.digParam, app.digDuration, app.digDly);
                pulseStr = sprintf('p%1.0f,%1.2f,%03.0f,%s,%04.0f,%04.0f!', app.pulseOn, app.pulseAmp,  app.pulseStart, app.pulseParam, app.pulseDur, app.pulseDly);           
                trainStr = sprintf('t%1.0f,%1.2f,%03.0f,%s,%04.0f,%s,%03.0f,%03.0f,%04.0f!', app.trainOn, app.trainAmp, app.trainStart, app.trainParam, app.trainDur, app.spikeParam, app.trainPrd, app.trainWid, app.trainDly);
                triStr = sprintf('^%1.0f,%1.2f,%03.0f,%03.0f,%03.0f!', app.triOn, app.triAmp, app.triDur, app.triPk, app.triStart);
                gausStr = sprintf('g%1.0f,%1.2f,%03.0f,%03.0f,%03.0f!',app.gausOn, app.gausAmp, app.gausCent, app.gausLeftWid, app.gausRightWid);
          
                dirStr = sprintf('<%s%s%s%s%s>\n', digStr,pulseStr, trainStr, triStr, gausStr);
                % wait until teensy ready to receive
                tic;
                if ~isReadyToReceive
                    while(~isReadyToReceive)
                        flushinput(app.SerialID);
%                         fprintf("waiting, %g:\n", isReadyToReceive)
                        isReadyToReceive = fread(app.SerialID, 1, 'uint8')==255;
                    end
                end
                
                % transmit data
                
                for char = dirStr
                    try 
                        fwrite(app.SerialID, char);
                    catch 
                        warning('Something went wrong on transmission of %s: Try turning Session On', char);
                    end
                end
                toc;
                fprintf('Transmitted string: %s\n',dirStr);
            end
            
            %restart serial
            fclose(app.SerialID);
            fopen(app.SerialID);
        end

        % Value changed function: TrainAmpEditField
        function TrainAmpEditFieldValueChanged(app, event)
            app.trainAmp = app.TrainAmpEditField.Value *app.A2V;
            
        end

        % Value changed function: TrainStartEditField
        function TrainStartEditFieldValueChanged(app, event)
            app.trainStart = app.TrainStartEditField.Value;
            
        end

        % Value changed function: TrainDurationEditField
        function TrainDurationEditFieldValueChanged(app, event)
            app.trainDur = app.TrainDurationEditField.Value;
            
        end

        % Value changed function: TrainPeriodEditField
        function TrainPeriodEditFieldValueChanged(app, event)
            app.trainPrd = app.TrainPeriodEditField.Value;
            
        end

        % Value changed function: TrainWidthEditField
        function TrainWidthEditFieldValueChanged(app, event)
            app.trainWid = app.TrainWidthEditField.Value;
            
        end

        % Value changed function: PulseTrainCheckBox
        function PulseTrainCheckBoxValueChanged(app, event)
            app.trainOn = app.PulseTrainCheckBox.Value;
            
        end

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)
            app.comNum = str2num(app.COMEditField.Value);
      
        end

        % Button pushed function: UpdatePreviewButton_2
        function UpdatePreviewButton_2Pushed(app, event)
            % set flags to false
            digDistFlag = false;
            pulseDistFlag = false;
            trainDistFlag = false;
            
            % convert time to distance
            v = 40/1000; %cm/ms Assumed top Speed
            
            % convert delays to approximate distance
            digDly_cm = ceil(app.digDly*v);
            pulseDly_cm = ceil(app.pulseDly*v);
            trainDly_cm = ceil(app.trainDly*v);

            % overlapping stimuli
            % instances where the start of a stim begins before another ends
            % assumes worst case with respect to delays causing overlap
            % train distance duration
            trainDistOverlap = and(app.trainParam == 'd', and((app.pulseStart<(app.trainStart + app.trainDur+app.trainDly*v)), app.pulseStart+app.pulseDly*v>=app.trainStart));
            pulseDistOverlap = and(app.pulseParam == 'd', and((app.trainStart<(app.pulseStart+app.pulseDur+app.pulseDly*v)), app.trainStart+app.trainDly*v>=app.pulseStart));
            
            % potential overlap
            trainTimeOverlap = and(app.trainParam =='s', and((app.pulseStart<(app.trainStart+app.trainDur*v+app.trainDly*v)), app.pulseStart+app.pulseDly*v>=app.trainStart));
            pulseTimeOverlap = and(app.pulseParam =='s', and((app.trainStart<(app.pulseStart+app.pulseDur*v+app.pulseDly*v)), app.trainStart+app.trainDly*v>=app.pulseStart));
             
            potentialOverlap = (trainTimeOverlap|| pulseTimeOverlap || trainDistOverlap || pulseDistOverlap);

            % warnings that fail the update
            if and(xor(app.triOn, app.gausOn), or(app.trainOn,app.pulseOn))
                warning("Cannot have Ramp or Gaussian active with Train or Analog Pulse. Use Digital Pulse");
            elseif(and(app.triOn, app.gausOn))
                warning("Cannot have Ramp and Gaussian active together");
            elseif(app.digOn && app.digParam == 'd' && (app.digDuration+app.digStart)>170)
                warning("Digital Pulse Cannot last longer than 170cm")
            elseif(app.pulseOn && app.pulseParam == 'd' && (app.pulseDur + app.pulseStart)>170)
                warning("Analog Pulse cannot last longer than 170cm")
            elseif(app.trainOn && app.trainParam=='d' && (app.trainStart+app.trainDur)>170)
                warning("Train cannot last longer than 170cm")
            elseif(app.trainOn && or(app.trainWid==0, app.trainPrd==0))
                warning("Pulse Train cannot be active with width or period of zero")
            elseif(and(app.spikeParam=='s', and(app.trainOn, app.trainWid < app.trainPrd)))
                warning("Pulse Width in Hz must be larger than Period");
            elseif(and(app.spikeParam == 'd', and(app.trainOn, app.trainWid > app.trainPrd)))
                warning("Pulse Width in cm must be smaller than Period");
            elseif(app.trainOn && and(app.trainParam==app.spikeParam, app.trainPrd == app.trainDur))
                warning("Set pulse train period to a different value than its duration");
            elseif(app.trainOn && app.trainParam=='d' && app.spikeParam=='d' && app.trainPrd >= app.trainDur)
                warning("Pulse train period in cm must be less than or equal to the duration in cm")
            elseif(app.triOn && or(app.triStart>app.triPk, app.triPk>app.triEnd))
                warning("Triangle Pulse distances must ascend. Check inputs again.")
            elseif(app.gausOn && (app.gausLeftWid<1 || 1>app.gausRightWid))
                warning("Gaussian distances must ascend. Check inputs again.")
            elseif(app.digOn && (app.digStart+digDly_cm>=170))
                warning("Digital pulse delay is too long. Move start position back or reduce delay.")
            elseif(app.pulseOn && (app.pulseStart+pulseDly_cm>=170))
                warning("Analog pulse delay is too long. Move start position back or reduce delay.")
            elseif(app.trainOn && (app.trainStart+trainDly_cm>=170))
                warning("Pulse train delay is too long. Move start position back or reduce delay.")
            else
            
                % warnings that could cause problems
                if(app.digOn && app.digParam == 's' && (app.digStart +app.digDuration/25 + app.digDly*v)>170)
                    warning("Digital Pulse may outlast 170 cm")
                end
                if(app.pulseOn && app.pulseParam =='s' && (app.pulseStart+app.pulseDur/25+app.digDly*v)>170)
                    warning("Analog Pulse may outlast 170cm")
                end
                if(app.trainOn && app.trainParam=='s' && (app.trainStart+app.trainDur/25+app.digDly*v)>170)
                    warning("Train may outlast 170cm")
                end    
                if(app.pulseOn && app.trainOn && potentialOverlap && ((app.pulseAmp + app.trainAmp) >3.3))
                    warning("Potentially overlapping pulse and train may exceed 6.6nA")
                end
                if(app.digOn && app.digParam=='d' && (app.digStart+app.digDuration+app.digDly*v)>170)
                    warning("Digital pulse may be delayed past 170cm")
                    digDistFlag = true; % flag this stnuli for possibly runnning long
                end
                if(app.pulseOn && app.pulseParam=='d' && (app.pulseStart+app.pulseDur+app.pulseDly*v)>170)
                    warning("Analog Pulse may be delayed past 170cm")
                    pulseDistFlag = true;
                end 
                if(app.trainOn && app.trainParam=='d' && (app.trainStart+app.trainDur+app.trainDly*v)>170)
                    warning("Train may be delayed past 170cm")
                    trainDistFlag = true;
                end  
                if(app.trainOn && app.trainParam=='s' && (app.trainStart+app.trainDur*v+app.trainDly*v)>170)
                    warning("Train may be delayed past 170cm")
                    trainDistFlag = true;
                end 
                
                % adjust the time-based x limits
                time_xlim = max([2000, app.digDuration+app.digDly, app.pulseDur+app.pulseDly, app.trainDur+app.trainDly]);
                if (app.trainParam == 'd' && app.spikeParam =='s')
                    time_xlim = max(time_xlim, app.trainDur*25);
                end
                time_xlim = ceil(time_xlim/1000)*1000; % round to nearest thousand
                
                % initialize signal arrays
                y = zeros(1,181); % analog signal
                pulseTime_y =zeros(1,time_xlim+1); %time-based pulse 
                dPulse_y = zeros(1,time_xlim+1); %time-based digital pulse
                dPulse_dist = zeros(1,181); % digital signal on the top graph
                spike_y = zeros(1,time_xlim+1); % time-based spikes
                triTime_y = zeros(1,time_xlim+1);
                gausTime_y = zeros(1,time_xlim+1);
                             
                % generate digital signal
                if app.digOn
                    if  strcmp(app.digParam, 'd')       
                        if digDistFlag
                            dPulseEnd = 170;
                        else
                            dPulseEnd = app.digStart+app.digDuration+digDly_cm;
                        end
                        dPulse_dist((app.digStart+1+digDly_cm):dPulseEnd) = 1;
                                           
                        if app.digDly ~= 0
                            dPulse_y(app.digDly+(1:app.digDuration/v)) = 1;
                        end                      

                    elseif  strcmp(app.digParam, 's') 
                      estimateDPulseLen = min([ceil(app.digDuration/25), (180-app.pulseStart-digDly_cm)]);
                      dPulse_dist(app.digStart+digDly_cm+(1:estimateDPulseLen)) = 1;
                      dPulse_y(app.digDly+(1:app.digDuration)) = 1;
                    end
                end
                
                % analog pulse
                if app.pulseOn
                  if  strcmp(app.pulseParam, 'd')
                      if pulseDistFlag
                          pulseEnd = 170-pulseDly_cm;
                      else
                          pulseEnd = (app.pulseStart+app.pulseDur);
                      end
                      pulseDistInds = pulseDly_cm+((app.pulseStart+1):pulseEnd);
                      y(pulseDistInds) = y(pulseDistInds) + app.pulseAmp/app.A2V;
                      if app.pulseDly ~= 0
                        pulseTime_y(app.pulseDly+(1:app.pulseDur/v)) = app.pulseAmp/app.A2V;
                      end
                  elseif strcmp(app.pulseParam, 's')
                      estimateDPulseLen = min(ceil(app.pulseDur/25), 180-app.pulseStart-pulseDly_cm);
                      
                      pulseTimeInds = pulseDly_cm + (app.pulseStart+1:(app.pulseStart+estimateDPulseLen));
                      y(pulseTimeInds) = y(pulseTimeInds) + app.pulseAmp/app.A2V;
                      pulseTime_y(app.pulseDly+(1:app.pulseDur)) = app.pulseAmp/app.A2V;
                  end
                end
                
                % pulse train
                if app.trainOn
                    if strcmp(app.trainParam, 'd')
                        if  strcmp(app.spikeParam, 'd')
                            single_pulse = zeros(1,app.trainPrd); 
                            single_pulse(1:app.trainWid) = app.trainAmp/app.A2V;
                            all_pulses = repmat(single_pulse, 1, ceil(app.trainDur/app.trainPrd));
                            all_pulses = all_pulses(1:app.trainDur);
                            
                            if trainDistFlag
                                trainDur_est= 170-app.trainStart-trainDly_cm;
                                all_pulses= all_pulses(1:trainDur_est);% show some spikes even though train may start too late
                            else
                                trainDur_est = app.trainDur; 
                            end
                            
                            train_inds = trainDly_cm+app.trainStart+(1:trainDur_est);
                            y(train_inds) = y(train_inds) + all_pulses;    
                            
                            if app.trainDly ~= 0   
                                num_pulses = floor(app.trainWid/app.trainPrd);
                                estimateSpikeWidth = app.trainWid * 25;
                                estimateSpikePrd = app.trainPrd *25;
                                
                                single_spike = zeros(1,estimateSpikePrd);
                                single_spike(1:estimateSpikeWidth) = app.trainAmp/app.A2V;
                                all_spikes = repmat(single_spike, 1, num_pulses);
                                spike_y(app.trainDly+(1:min(length(all_spikes),app.trainDur))) = all_spikes(1:min(length(all_spikes),app.trainDur));       
                            end
                       
                        elseif strcmp(app.spikeParam, 's')
        
                            prdTs = round(1/app.trainPrd*1000);
                            width = round(1/app.trainWid*1000);
                            estTime = app.trainDur*25;
                            numInTime = ceil(estTime/prdTs);
                            single_spike = zeros(1,prdTs);
                            single_spike(1:width) = app.trainAmp/app.A2V;
                            all_spikes = repmat(single_spike, 1, numInTime);
                            spike_y(app.trainDly+(1:min(time_xlim, length(all_spikes)))) = all_spikes(1:min(time_xlim, length(all_spikes)));
                            
                            sample_spikes = zeros(1,app.trainDur);
                            sample_spikes(1:2:end) = app.trainAmp/app.A2V;
                            if trainDistFlag
                                trainDur_est= 170-app.trainStart-trainDly_cm;
                                sample_spikes= sample_spikes(1:trainDur_est);% show some spikes even though train may start too late
                            else
                                trainDur_est = app.trainDur; 
                            end
                            
                            trainInds_distEst = trainDly_cm+app.trainStart+(1:trainDur_est);
                            y(trainInds_distEst) = y(trainInds_distEst) + sample_spikes;


                        end
                    end        
                    if strcmp(app.trainParam, 's')
                        if  strcmp(app.spikeParam, 'd')


                            estimateSpikeWidth = app.trainWid * 25;
                            estimateSpikePrd = app.trainPrd *25;
                            estTrainDur = app.trainDur/25;
                            num_pulses = min(ceil(estTrainDur/app.trainPrd), ceil((180-app.trainStart-trainDly_cm)/app.trainPrd)); % no more than number length of lap
                            single_pulse = zeros(1,app.trainPrd); 
                            single_pulse(1:app.trainWid) = app.trainAmp/app.A2V;
                            x_pulses = repmat(single_pulse, 1, num_pulses);


                            single_spike = zeros(1,estimateSpikePrd);
                            single_spike(1:estimateSpikeWidth) = app.trainAmp/app.A2V;
                            all_spikes = repmat(single_spike, 1, num_pulses);
                            spike_y(app.trainDly+(1:min(length(all_spikes),app.trainDur))) = all_spikes(1:min(length(all_spikes),app.trainDur));             
                            
                            
                            train_inds = [trainDly_cm+app.trainStart+(1:length(x_pulses))];
                            
                            % truncate spikes to end of lap
                            maxInds = find(train_inds == 180);
                            if ~isempty(maxInds)
                                train_inds = train_inds(1:maxInds);
                                x_pulses = x_pulses(1:maxInds);
                            end
                            
                            y(train_inds) = y(train_inds) + x_pulses;    
                        
                        elseif strcmp(app.spikeParam, 's')
        
                            prdTs = round(1/app.trainPrd*1000);
                            width = round(1/app.trainWid*1000);
                            single_spike = zeros(1,prdTs);
                            single_spike(1:width) = app.trainAmp/app.A2V;
                            all_spikes = repmat(single_spike, 1, ceil(app.trainDur/app.trainPrd));
                            spike_y(app.trainDly+(1:min(length(all_spikes),app.trainDur))) = all_spikes(1:min(length(all_spikes), app.trainDur));
                            
                            estimateTrainDur = min(ceil(app.trainDur/25), (180-app.trainStart));
                            sample_spikes = zeros(1,estimateTrainDur);
                            sample_spikes(1:2:end) = app.trainAmp/app.A2V;
                            
                            if trainDistFlag
                                trainDur_est = 170-trainDly_cm-app.trainStart;
                                sample_spikes = sample_spikes(1:trainDur_est);
                            else
                                trainDur_est = estimateTrainDur;
                            end
                            
                            train_inds = trainDly_cm+app.trainStart+(1:trainDur_est);                                                 
                            y(train_inds) = y(train_inds) + sample_spikes;


                        end
                    end        
                end
                
                % triangle pulse
                if app.triOn
                    currTriAmp = app.triAmp/app.A2V;
                    triSlopeUp = currTriAmp/(app.triPk-app.triStart);
                    triSlopeDown = currTriAmp/(app.triPk-app.triEnd);
                    triPulseUp = ((0:(app.triPk-app.triStart-1)))*triSlopeUp;
                    triPulseDown = ((app.triPk:app.triEnd) - app.triPk)*triSlopeDown+currTriAmp;
                    
                    triPulse = [triPulseUp,triPulseDown];
                    
                    
                    triInds = (app.triStart+1):(app.triEnd+1);
                    %fprintf("size UP:%g, size down: %g, indSize:%g \n", length(triPulseUp), length(triPulseDown), length(triInds))
                   
                    
                    y(triInds) = y(triInds)+triPulse;
                    
                end
                
                % gaussian
                if app.gausOn
                    currGausAmp = app.gausAmp/app.A2V;
                    gausInds = cumsum(ones(1,181));
                    % up gaussian
                    upInds = 1:(app.gausCent+1);
                    y(upInds) = currGausAmp*exp(-log(2)*((gausInds(upInds)-app.gausCent)/app.gausLeftWid).^2);
                    
                    % down gaussian
                    downInds = (app.gausCent+1):170;
                    y(downInds) = currGausAmp*exp(-log(2)*((gausInds(downInds)-app.gausCent)/app.gausRightWid).^2);
                    %fprintf ("Amp: %g, Cent: %g, L:%g, R:%g", app.gausAmp, app.gausCent, app.gausLeftWid, app.gausRightWid)
                    
                end
                
                cla(app.UIAxes)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, y);
                plot(app.UIAxes, dPulse_dist);
                legend(app.UIAxes, {'Analog Out','Digital Out'})
                hold(app.UIAxes, 'off')
                         
                cla(app.UIAxes2)
                hold(app.UIAxes2, 'on')
                xlim(app.UIAxes2, [0 time_xlim])
                plot(app.UIAxes2, spike_y)
                plot(app.UIAxes2, dPulse_y)
                plot(app.UIAxes2, pulseTime_y)
                legend(app.UIAxes2, {'Spike Train','Digital Pulse','Analog Pulse'})
                hold(app.UIAxes2, 'off')
            end
        end

        % Value changed function: DigitalPulseCheckBox
        function DigitalPulseCheckBoxValueChanged(app, event)
            app.digOn = app.DigitalPulseCheckBox.Value;
            
        end

        % Value changed function: DigitalStartEditField
        function DigitalStartEditFieldValueChanged(app, event)
            app.digStart = app.DigitalStartEditField.Value;
            
        end

        % Value changed function: DigitalDurationEditField
        function DigitalDurationEditFieldValueChanged(app, event)
            app.digDuration = app.DigitalDurationEditField.Value;
            
        end

        % Value changed function: DigitalDurationDropDown
        function DigitalDurationDropDownValueChanged(app, event)
            dval = app.DigitalDurationDropDown.Value;
            if strcmp(dval, 'ms')
                app.digParam = 's';
            else
                app.digParam = 'd';
            end
        end

        % Value changed function: DigitalDelayEditField
        function DigitalDelayEditFieldValueChanged(app, event)
            app.digDly = app.DigitalDelayEditField.Value;
            
        end

        % Value changed function: PulseDropDown
        function PulseDropDownValueChanged(app, event)
            pval = app.PulseDropDown.Value;
            if strcmp(pval, 'ms')
                app.pulseParam = 's';
            else
                app.pulseParam = 'd';
            end
        end

        % Value changed function: TrainParamDropDown
        function TrainParamDropDownValueChanged(app, event)
            tparam = app.TrainParamDropDown.Value;
            if strcmp(tparam, 'ms')
                app.trainParam = 's';
            else
                app.trainParam = 'd';
            end
        end

        % Value changed function: SpikeParamDropDown
        function SpikeParamDropDownValueChanged(app, event)
            sval = app.SpikeParamDropDown.Value;
            if strcmp(sval, 'Hz')
                app.spikeParam = 's';
            else
                app.spikeParam = 'd';
            end
        end

        % Value changed function: TrainDelayEditField
        function TrainDelayEditFieldValueChanged(app, event)
            app.trainDly = app.TrainDelayEditField.Value;
        end

        % Value changed function: PulseDelayEditField
        function PulseDelayEditFieldValueChanged(app, event)
            app.pulseDly = app.PulseDelayEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {489, 489};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {409, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.DigitalPulseTab = uitab(app.TabGroup);
            app.DigitalPulseTab.Title = 'Digital Pulse';
            app.StartcmEditField_4Label_2 = uilabel(app.DigitalPulseTab);
            app.StartcmEditField_4Label_2.Text = 'Start (cm)';
            app.DigitalStartEditField = uieditfield(app.DigitalPulseTab, 'numeric');
            app.DigitalStartEditField.Limits = [0 170];
            app.DigitalStartEditField.RoundFractionalValues = 'on';
            app.DigitalStartEditField.ValueDisplayFormat = '%3.0f';
            app.DigitalStartEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalStartEditFieldValueChanged, true);
            app.DurationEditField_2Label = uilabel(app.DigitalPulseTab);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DigitalDurationEditField = uieditfield(app.DigitalPulseTab, 'numeric');
            app.DigitalDurationEditField.Limits = [0 9999];
            app.DigitalDurationEditField.RoundFractionalValues = 'on';
            app.DigitalDurationEditField.ValueDisplayFormat = '%3.0f';
            app.DigitalDurationEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalDurationEditFieldValueChanged, true);
            app.DigitalDurationDropDown = uidropdown(app.DigitalPulseTab);
            app.DigitalDurationDropDown.Items = {'ms', 'cm'};
            app.DigitalDurationDropDown.ValueChangedFcn = createCallbackFcn(app, @DigitalDurationDropDownValueChanged, true);
            app.DigitalDurationDropDown.Value = 'ms';
            app.DelaymsLabel = uilabel(app.DigitalPulseTab);
            app.DelaymsLabel.Text = 'Delay (ms)';
            app.DigitalDelayEditField = uieditfield(app.DigitalPulseTab, 'numeric');
            app.DigitalDelayEditField.Limits = [0 9999];
            app.DigitalDelayEditField.RoundFractionalValues = 'on';
            app.DigitalDelayEditField.ValueDisplayFormat = '%3.0f';
            app.DigitalDelayEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalDelayEditFieldValueChanged, true);
            app.PulseTab = uitab(app.TabGroup);
            app.PulseTab.Title = 'Pulse';
            app.AmplitudenAEditFieldLabel = uilabel(app.PulseTab);
            app.AmplitudenAEditFieldLabel.Text = 'Amplitude (nA)';
            app.PulseAmpEditField = uieditfield(app.PulseTab, 'numeric');
            app.PulseAmpEditField.Limits = [0 6.6];
            app.PulseAmpEditField.ValueDisplayFormat = '%1.2f';
            app.PulseAmpEditField.ValueChangedFcn = createCallbackFcn(app, @PulseAmpEditFieldValueChanged, true);
            app.StartcmEditFieldLabel = uilabel(app.PulseTab);
            app.StartcmEditFieldLabel.Text = 'Start (cm)';
            app.PulseStartEditField = uieditfield(app.PulseTab, 'numeric');
            app.PulseStartEditField.Limits = [0 170];
            app.PulseStartEditField.RoundFractionalValues = 'on';
            app.PulseStartEditField.ValueDisplayFormat = '%3.0f';
            app.PulseStartEditField.ValueChangedFcn = createCallbackFcn(app, @PulseStartEditFieldValueChanged, true);
            app.DurationEditFieldLabel = uilabel(app.PulseTab);
            app.DurationEditFieldLabel.Text = 'Duration ';
            app.PulseDurationEditField = uieditfield(app.PulseTab, 'numeric');
            app.PulseDurationEditField.Limits = [0 9999];
            app.PulseDurationEditField.RoundFractionalValues = 'on';
            app.PulseDurationEditField.ValueDisplayFormat = '%4.0f';
            app.PulseDurationEditField.ValueChangedFcn = createCallbackFcn(app, @PulseDurationEditFieldValueChanged, true);
            app.PulseDropDown = uidropdown(app.PulseTab);
            app.PulseDropDown.Items = {'ms', 'cm'};
            app.PulseDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseDropDownValueChanged, true);
            app.PulseDropDown.Value = 'ms';
            app.DelaymsLabel_2 = uilabel(app.PulseTab);
            app.DelaymsLabel_2.Text = 'Delay (ms)';
            app.PulseDelayEditField = uieditfield(app.PulseTab, 'numeric');
            app.PulseDelayEditField.Limits = [0 9999];
            app.PulseDelayEditField.RoundFractionalValues = 'on';
            app.PulseDelayEditField.ValueDisplayFormat = '%4.0f';
            app.PulseDelayEditField.ValueChangedFcn = createCallbackFcn(app, @PulseDelayEditFieldValueChanged, true);
            app.PulseTrainTab = uitab(app.TabGroup);
            app.PulseTrainTab.Title = 'Pulse Train';
            app.AmplitudenAEditField_2Label = uilabel(app.PulseTrainTab);
            app.AmplitudenAEditField_2Label.Text = 'Amplitude (nA)';
            app.TrainAmpEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainAmpEditField.Limits = [0 6.6];
            app.TrainAmpEditField.ValueDisplayFormat = '%1.2f';
            app.TrainAmpEditField.ValueChangedFcn = createCallbackFcn(app, @TrainAmpEditFieldValueChanged, true);
            app.StartcmEditField_4Label = uilabel(app.PulseTrainTab);
            app.StartcmEditField_4Label.Text = 'Start (cm)';
            app.TrainStartEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainStartEditField.Limits = [0 170];
            app.TrainStartEditField.RoundFractionalValues = 'on';
            app.TrainStartEditField.ValueDisplayFormat = '%3.0f';
            app.TrainStartEditField.ValueChangedFcn = createCallbackFcn(app, @TrainStartEditFieldValueChanged, true);
            app.DurationEditField_3Label = uilabel(app.PulseTrainTab);
            app.DurationEditField_3Label.Text = 'Duration';
            app.TrainDurationEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainDurationEditField.Limits = [0 9999];
            app.TrainDurationEditField.RoundFractionalValues = 'on';
            app.TrainDurationEditField.ValueDisplayFormat = '%3.0f';
            app.TrainDurationEditField.ValueChangedFcn = createCallbackFcn(app, @TrainDurationEditFieldValueChanged, true);
            app.PeriodEditFieldLabel = uilabel(app.PulseTrainTab);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.TrainPeriodEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainPeriodEditField.Limits = [0 300];
            app.TrainPeriodEditField.RoundFractionalValues = 'on';
            app.TrainPeriodEditField.ValueDisplayFormat = '%.0f';
            app.TrainPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @TrainPeriodEditFieldValueChanged, true);
            app.PulseWidthEditFieldLabel = uilabel(app.PulseTrainTab);
            app.PulseWidthEditFieldLabel.Text = 'Pulse Width';
            app.TrainWidthEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainWidthEditField.Limits = [0 999];
            app.TrainWidthEditField.RoundFractionalValues = 'on';
            app.TrainWidthEditField.ValueDisplayFormat = '%.0f';
            app.TrainWidthEditField.ValueChangedFcn = createCallbackFcn(app, @TrainWidthEditFieldValueChanged, true);
            app.TrainParamDropDown = uidropdown(app.PulseTrainTab);
            app.TrainParamDropDown.Items = {'ms', 'cm'};
            app.TrainParamDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainParamDropDownValueChanged, true);
            app.TrainParamDropDown.Value = 'ms';
            app.SpikeParamDropDown = uidropdown(app.PulseTrainTab);
            app.SpikeParamDropDown.Items = {'Hz', 'cm'};
            app.SpikeParamDropDown.ValueChangedFcn = createCallbackFcn(app, @SpikeParamDropDownValueChanged, true);
            app.SpikeParamDropDown.Value = 'Hz';
            app.DelaymsLabel_3 = uilabel(app.PulseTrainTab);
            app.DelaymsLabel_3.Text = 'Delay (ms)';
            app.TrainDelayEditField = uieditfield(app.PulseTrainTab, 'numeric');
            app.TrainDelayEditField.Limits = [0 9999];
            app.TrainDelayEditField.RoundFractionalValues = 'on';
            app.TrainDelayEditField.ValueDisplayFormat = '%3.0f';
            app.TrainDelayEditField.ValueChangedFcn = createCallbackFcn(app, @TrainDelayEditFieldValueChanged, true);
            app.TriangleTab = uitab(app.TabGroup);
            app.TriangleTab.Title = 'Triangle';
            app.AmplitudenAEditField_3Label = uilabel(app.TriangleTab);
            app.AmplitudenAEditField_3Label.Text = 'Amplitude (nA)';
            app.AmplitudenAEditField_3 = uieditfield(app.TriangleTab, 'numeric');
            app.AmplitudenAEditField_3.Limits = [0 6.6];
            app.AmplitudenAEditField_3.ValueDisplayFormat = '%1.2f';
            app.AmplitudenAEditField_3.ValueChangedFcn = createCallbackFcn(app, @AmplitudenAEditField_3ValueChanged, true);
            app.StartcmEditField_5Label = uilabel(app.TriangleTab);
            app.StartcmEditField_5Label.Text = 'Start (cm)';
            app.StartcmEditField_3 = uieditfield(app.TriangleTab, 'numeric');
            app.StartcmEditField_3.Limits = [0 170];
            app.StartcmEditField_3.RoundFractionalValues = 'on';
            app.StartcmEditField_3.ValueDisplayFormat = '%3.0f';
            app.StartcmEditField_3.ValueChangedFcn = createCallbackFcn(app, @StartcmEditField_3ValueChanged, true);
            app.PeakcmEditFieldLabel = uilabel(app.TriangleTab);
            app.PeakcmEditFieldLabel.Text = 'Peak (cm)';
            app.PeakcmEditField = uieditfield(app.TriangleTab, 'numeric');
            app.PeakcmEditField.Limits = [0 170];
            app.PeakcmEditField.RoundFractionalValues = 'on';
            app.PeakcmEditField.ValueDisplayFormat = '%3.0f';
            app.PeakcmEditField.ValueChangedFcn = createCallbackFcn(app, @PeakcmEditFieldValueChanged, true);
            app.EndcmLabel = uilabel(app.TriangleTab);
            app.EndcmLabel.Text = 'End (cm)';
            app.EndcmEditField = uieditfield(app.TriangleTab, 'numeric');
            app.EndcmEditField.Limits = [0 170];
            app.EndcmEditField.RoundFractionalValues = 'on';
            app.EndcmEditField.ValueDisplayFormat = '%3.0f';
            app.EndcmEditField.ValueChangedFcn = createCallbackFcn(app, @EndcmEditFieldValueChanged, true);
            app.GaussianTab = uitab(app.TabGroup);
            app.GaussianTab.Title = 'Gaussian';
            app.AmplitudenAEditField_4Label = uilabel(app.GaussianTab);
            app.AmplitudenAEditField_4Label.Text = 'Amplitude (nA)';
            app.AmplitudenAEditField_4 = uieditfield(app.GaussianTab, 'numeric');
            app.AmplitudenAEditField_4.Limits = [0 6.6];
            app.AmplitudenAEditField_4.ValueDisplayFormat = '%1.2f';
            app.AmplitudenAEditField_4.ValueChangedFcn = createCallbackFcn(app, @AmplitudenAEditField_4ValueChanged, true);
            app.LeftWidthAtHalfMaxEditFieldLabel = uilabel(app.GaussianTab);
            app.LeftWidthAtHalfMaxEditFieldLabel.Text = 'Left Width At Half Max';
            app.LeftWidthAtHalfMaxEditField = uieditfield(app.GaussianTab, 'numeric');
            app.LeftWidthAtHalfMaxEditField.Limits = [0 170];
            app.LeftWidthAtHalfMaxEditField.RoundFractionalValues = 'on';
            app.LeftWidthAtHalfMaxEditField.ValueDisplayFormat = '%3.0f';
            app.LeftWidthAtHalfMaxEditField.ValueChangedFcn = createCallbackFcn(app, @LeftWidthAtHalfMaxEditFieldValueChanged, true);
            app.CenterEditFieldLabel = uilabel(app.GaussianTab);
            app.CenterEditFieldLabel.Text = 'Center';
            app.CenterEditField = uieditfield(app.GaussianTab, 'numeric');
            app.CenterEditField.Limits = [0 170];
            app.CenterEditField.RoundFractionalValues = 'on';
            app.CenterEditField.ValueDisplayFormat = '%3.0f';
            app.CenterEditField.ValueChangedFcn = createCallbackFcn(app, @CenterEditFieldValueChanged, true);
            app.RightWidthAtHalfMaxLabel = uilabel(app.GaussianTab);
            app.RightWidthAtHalfMaxLabel.Text = 'Right Width At Half Max';
            app.RightWidthAtHalfMaxEditField = uieditfield(app.GaussianTab, 'numeric');
            app.RightWidthAtHalfMaxEditField.Limits = [0 170];
            app.RightWidthAtHalfMaxEditField.RoundFractionalValues = 'on';
            app.RightWidthAtHalfMaxEditField.ValueDisplayFormat = '%3.0f';
            app.RightWidthAtHalfMaxEditField.ValueChangedFcn = createCallbackFcn(app, @RightWidthAtHalfMaxEditFieldValueChanged, true);
            app.PulseCheckBox = uicheckbox(app.LeftPanel);
            app.PulseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseCheckBoxValueChanged, true);
            app.PulseCheckBox.Text = 'Pulse';
            app.PulseTrainCheckBox = uicheckbox(app.LeftPanel);
            app.PulseTrainCheckBox.ValueChangedFcn = createCallbackFcn(app, @PulseTrainCheckBoxValueChanged, true);
            app.PulseTrainCheckBox.Text = 'Pulse Train';
            app.GaussianCheckBox = uicheckbox(app.LeftPanel);
            app.GaussianCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianCheckBoxValueChanged, true);
            app.GaussianCheckBox.Text = 'Gaussian';
            app.TriangleCheckBox = uicheckbox(app.LeftPanel);
            app.TriangleCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriangleCheckBoxValueChanged, true);
            app.TriangleCheckBox.Text = 'Triangle';
            app.SessionSwitchLabel = uilabel(app.LeftPanel);
            app.SessionSwitchLabel.Text = 'Session';
            app.SessionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SessionSwitch.Items = {'Clear', 'On'};
            app.SessionSwitch.ValueChangedFcn = createCallbackFcn(app, @SessionSwitchValueChanged, true);
            app.SessionSwitch.Value = 'Clear';
            app.UpdateButton = uibutton(app.LeftPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.COMEditFieldLabel = uilabel(app.LeftPanel);
            app.COMEditFieldLabel.Text = 'COM #';
            app.COMEditField = uieditfield(app.LeftPanel, 'text');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = '4';
            app.DigitalPulseCheckBox = uicheckbox(app.LeftPanel);
            app.DigitalPulseCheckBox.ValueChangedFcn = createCallbackFcn(app, @DigitalPulseCheckBoxValueChanged, true);
            app.DigitalPulseCheckBox.Text = 'Digital Pulse';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Stimulus Preview')
            xlabel(app.UIAxes, 'Distance (cm)')
            ylabel(app.UIAxes, 'Amplitude (nA)')
            app.UIAxes.XLim = [0 180];
            app.UIAxes.YLim = [0 6.6];
            app.UIAxes.XTick = [0 20 40 60 80 100 120 140 160 180];
            app.UpdatePreviewButton_2 = uibutton(app.RightPanel, 'push');
            app.UpdatePreviewButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButton_2Pushed, true);
            app.UpdatePreviewButton_2.Text = 'Update Preview';
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Time-Based Stimuli')
            xlabel(app.UIAxes2, 'Time (ms)')
            ylabel(app.UIAxes2, 'Amplitude (nA)')
            app.UIAxes2.XLim = [0 1000];
            app.UIAxes2.YLim = [0 6.6];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Controllable_Fgen_v20_Delays

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end