classdef PeripheralStimuliControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        MotionCueTab                    matlab.ui.container.Tab
        NumberofRectanglesLabel         matlab.ui.control.Label
        DigitalStartEditField           matlab.ui.control.NumericEditField
        TotalRectanglesWidthLabel       matlab.ui.control.Label
        DigitalDurationEditField        matlab.ui.control.NumericEditField
        RectangleHeightLabel            matlab.ui.control.Label
        ofscreenLabel                   matlab.ui.control.Label
        ofscreenLabel_2                 matlab.ui.control.Label
        ColorDropDownLabel              matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        DigitalDelayEditField           matlab.ui.control.NumericEditField
        ColorDropDown_2Label            matlab.ui.control.Label
        ColorDropDown_2                 matlab.ui.control.DropDown
        RectangleHeightLabel_2          matlab.ui.control.Label
        DigitalDelayEditField_2         matlab.ui.control.NumericEditField
        TotalRectanglesWidthLabel_3     matlab.ui.control.Label
        DigitalDurationEditField_3      matlab.ui.control.NumericEditField
        RewardStimuliTab                matlab.ui.container.Tab
        RewardLocationmmEditFieldLabel  matlab.ui.control.Label
        RewardLocationmmEditField       matlab.ui.control.NumericEditField
        ManualStimuliTab                matlab.ui.container.Tab
        TotalRectanglesWidthLabel_4     matlab.ui.control.Label
        DigitalDurationEditField_4      matlab.ui.control.NumericEditField
        RectangleHeightLabel_3          matlab.ui.control.Label
        DigitalDelayEditField_3         matlab.ui.control.NumericEditField
        NumberofRectanglesLabel_2       matlab.ui.control.Label
        DigitalStartEditField_2         matlab.ui.control.NumericEditField
        CueDurationmsLabel              matlab.ui.control.Label
        DigitalDelayEditField_4         matlab.ui.control.NumericEditField
        ColorDropDown_3Label            matlab.ui.control.Label
        ColorDropDown_3                 matlab.ui.control.DropDown
        RewardStimuliCheckBox           matlab.ui.control.CheckBox
        SessionSwitchLabel              matlab.ui.control.Label
        SessionSwitch                   matlab.ui.control.Switch
        COMEditFieldLabel               matlab.ui.control.Label
        COMEditField                    matlab.ui.control.EditField
        MotionCueCheckBox               matlab.ui.control.CheckBox
        CueSpeedEditFieldLabel          matlab.ui.control.Label
        DisplayCuesSwitchLabel          matlab.ui.control.Label
        DisplayCuesSwitch               matlab.ui.control.ToggleSwitch
        CueSpeedEditField               matlab.ui.control.NumericEditField
        FlashManualCueButton            matlab.ui.control.Button
        ShowManualCuefor1screenButton   matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)        
       
        rectWidth = 0;
        rectHeight = 0;
        spaceWidth = 0; 
        
        lapDistance = 0; %cm
        numRects = 0;
        fillScreenPercent = 0; % percent of width taken up by white space
        heightPercent = 0;
        rectVelo = 0;
        
        TimeInterval=0.01;  %time interval (s) between each input.

        
        SerialID
        comNum = 5;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SessionSwitch
        function SessionSwitchValueChanged(app, event)
            isStartOn  = app.SessionSwitch.Value;
            comStr = sprintf("COM%g", app.comNum);
            switch isStartOn 
                case 'On'
                    coms = instrfind;
                    if ~isempty(coms) 
                        fclose(coms);
                        delete(coms);
                    end
                    
                    app.SerialID = serial(comStr,'BaudRate',9600);
                    fopen(app.SerialID);
%                     clearstr = '<dp0,0.00,000,000!t0,0.00,000,000,00.000,00.000!^0,0.00,000,000,000!g0,0,0,0,0!>';
%                     for char = clearstr
%                         fwrite(app.SerialID, char)
%                     end
                case 'Clear'
                    fclose(app.SerialID);
                    delete(app.SerialID);
                    
            end
        end

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)
            app.comNum = str2num(app.COMEditField.Value);
      
        end

        % Value changed function: DisplayCuesSwitch
        function DisplayCuesSwitchValueChanged(app, event)
            value = app.DisplayCuesSwitch.Value;
            fprintf(value)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {489, 489};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {405, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.MotionCueTab = uitab(app.TabGroup);
            app.MotionCueTab.Title = 'Motion Cue';
            app.NumberofRectanglesLabel = uilabel(app.MotionCueTab);
            app.NumberofRectanglesLabel.Text = 'Number of Rectangles';
            app.DigitalStartEditField = uieditfield(app.MotionCueTab, 'numeric');
            app.DigitalStartEditField.Limits = [0 170];
            app.DigitalStartEditField.RoundFractionalValues = 'on';
            app.DigitalStartEditField.ValueDisplayFormat = '%3.0f';
            app.TotalRectanglesWidthLabel = uilabel(app.MotionCueTab);
            app.TotalRectanglesWidthLabel.Text = 'Total Rectangles'' Width';
            app.DigitalDurationEditField = uieditfield(app.MotionCueTab, 'numeric');
            app.DigitalDurationEditField.Limits = [0 100];
            app.DigitalDurationEditField.RoundFractionalValues = 'on';
            app.DigitalDurationEditField.ValueDisplayFormat = '%3.0f';
            app.RectangleHeightLabel = uilabel(app.MotionCueTab);
            app.RectangleHeightLabel.Text = 'Rectangle Height';
            app.DigitalDelayEditField = uieditfield(app.MotionCueTab, 'numeric');
            app.DigitalDelayEditField.Limits = [0 100];
            app.DigitalDelayEditField.RoundFractionalValues = 'on';
            app.DigitalDelayEditField.ValueDisplayFormat = '%3.0f';
            app.ofscreenLabel = uilabel(app.MotionCueTab);
            app.ofscreenLabel.Text = '% of screen';
            app.ofscreenLabel_2 = uilabel(app.MotionCueTab);
            app.ofscreenLabel_2.Text = '% of screen';
            app.ColorDropDownLabel = uilabel(app.MotionCueTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.MotionCueTab);
            app.ColorDropDown.Items = {'White', 'Grey', 'Blue', 'Green', 'Red'};
            app.ColorDropDown.Value = 'White';
            app.RewardStimuliTab = uitab(app.TabGroup);
            app.RewardStimuliTab.Title = 'Reward Stimuli';
            app.RewardLocationmmEditFieldLabel = uilabel(app.RewardStimuliTab);
            app.RewardLocationmmEditFieldLabel.Text = 'Reward Location (mm)';
            app.RewardLocationmmEditField = uieditfield(app.RewardStimuliTab, 'numeric');
            app.RewardLocationmmEditField.Limits = [0 4000];
            app.RewardLocationmmEditField.ValueDisplayFormat = '%3.0f';
            app.TotalRectanglesWidthLabel_3 = uilabel(app.RewardStimuliTab);
            app.TotalRectanglesWidthLabel_3.Text = 'Total Rectangles'' Width';
            app.DigitalDurationEditField_3 = uieditfield(app.RewardStimuliTab, 'numeric');
            app.DigitalDurationEditField_3.Limits = [0 100];
            app.DigitalDurationEditField_3.RoundFractionalValues = 'on';
            app.DigitalDurationEditField_3.ValueDisplayFormat = '%3.0f';
            app.RectangleHeightLabel_2 = uilabel(app.RewardStimuliTab);
            app.RectangleHeightLabel_2.Text = 'Rectangle Height';
            app.DigitalDelayEditField_2 = uieditfield(app.RewardStimuliTab, 'numeric');
            app.DigitalDelayEditField_2.Limits = [0 100];
            app.DigitalDelayEditField_2.RoundFractionalValues = 'on';
            app.DigitalDelayEditField_2.ValueDisplayFormat = '%3.0f';
            app.ColorDropDown_2Label = uilabel(app.RewardStimuliTab);
            app.ColorDropDown_2Label.Text = 'Color';
            app.ColorDropDown_2 = uidropdown(app.RewardStimuliTab);
            app.ColorDropDown_2.Items = {'White', 'Grey', 'Blue', 'Green', 'Red'};
            app.ColorDropDown_2.Value = 'White';
            app.ManualStimuliTab = uitab(app.TabGroup);
            app.ManualStimuliTab.Title = 'Manual Stimuli';
            app.TotalRectanglesWidthLabel_4 = uilabel(app.ManualStimuliTab);
            app.TotalRectanglesWidthLabel_4.Text = 'Total Rectangles'' Width';
            app.DigitalDurationEditField_4 = uieditfield(app.ManualStimuliTab, 'numeric');
            app.DigitalDurationEditField_4.Limits = [0 100];
            app.DigitalDurationEditField_4.RoundFractionalValues = 'on';
            app.DigitalDurationEditField_4.ValueDisplayFormat = '%3.0f';
            app.RectangleHeightLabel_3 = uilabel(app.ManualStimuliTab);
            app.RectangleHeightLabel_3.Text = 'Rectangle Height';
            app.DigitalDelayEditField_3 = uieditfield(app.ManualStimuliTab, 'numeric');
            app.DigitalDelayEditField_3.Limits = [0 100];
            app.DigitalDelayEditField_3.RoundFractionalValues = 'on';
            app.DigitalDelayEditField_3.ValueDisplayFormat = '%3.0f';
            app.ColorDropDown_3Label = uilabel(app.ManualStimuliTab);
            app.ColorDropDown_3Label.Text = 'Color';
            app.ColorDropDown_3 = uidropdown(app.ManualStimuliTab);
            app.ColorDropDown_3.Items = {'White', 'Grey', 'Blue', 'Green', 'Red'};
            app.ColorDropDown_3.Value = 'White';
            app.NumberofRectanglesLabel_2 = uilabel(app.ManualStimuliTab);
            app.NumberofRectanglesLabel_2.Text = 'Number of Rectangles';
            app.DigitalStartEditField_2 = uieditfield(app.ManualStimuliTab, 'numeric');
            app.DigitalStartEditField_2.Limits = [0 170];
            app.DigitalStartEditField_2.RoundFractionalValues = 'on';
            app.DigitalStartEditField_2.ValueDisplayFormat = '%3.0f';
            app.CueDurationmsLabel = uilabel(app.ManualStimuliTab);
            app.CueDurationmsLabel.Text = 'Cue Duration (ms)';
            app.DigitalDelayEditField_4 = uieditfield(app.ManualStimuliTab, 'numeric');
            app.DigitalDelayEditField_4.Limits = [0 9999];
            app.DigitalDelayEditField_4.RoundFractionalValues = 'on';
            app.DigitalDelayEditField_4.ValueDisplayFormat = '%4.0f';
            app.RewardStimuliCheckBox = uicheckbox(app.LeftPanel);
            app.RewardStimuliCheckBox.Text = 'Reward Stimuli';
            app.SessionSwitchLabel = uilabel(app.LeftPanel);
            app.SessionSwitchLabel.Text = 'Session';
            app.SessionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SessionSwitch.Items = {'Clear', 'On'};
            app.SessionSwitch.ValueChangedFcn = createCallbackFcn(app, @SessionSwitchValueChanged, true);
            app.SessionSwitch.Value = 'Clear';
            app.COMEditFieldLabel = uilabel(app.LeftPanel);
            app.COMEditFieldLabel.Text = 'COM #';
            app.COMEditField = uieditfield(app.LeftPanel, 'text');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = '5';
            app.MotionCueCheckBox = uicheckbox(app.LeftPanel);
            app.MotionCueCheckBox.Text = 'Motion Cue';
            app.CueSpeedEditFieldLabel = uilabel(app.LeftPanel);
            app.CueSpeedEditFieldLabel.Text = 'Cue Speed';
            app.CueSpeedEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CueSpeedEditField.Limits = [0 100];
            app.CueSpeedEditField.ValueDisplayFormat = '%g';
            app.CueSpeedEditField.Value = 1;
            app.DisplayCuesSwitchLabel = uilabel(app.LeftPanel);
            app.DisplayCuesSwitchLabel.Text = 'Display Cues';
            app.DisplayCuesSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.DisplayCuesSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplayCuesSwitchValueChanged, true);
            app.FlashManualCueButton = uibutton(app.LeftPanel, 'push');
            app.FlashManualCueButton.Text = 'Flash Manual Cue';
            app.ShowManualCuefor1screenButton = uibutton(app.LeftPanel, 'push');
            app.ShowManualCuefor1screenButton.Text = 'Show Manual Cue for 1 screen';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PeripheralStimuliControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end