classdef PeripheralStimuliControl_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        MotionCueTab                    matlab.ui.container.Tab
        NumberofRectanglesLabel         matlab.ui.control.Label
        MotionNumRectsEditField         matlab.ui.control.NumericEditField
        TotalRectanglesWidthLabel       matlab.ui.control.Label
        MotionRectWidEditField          matlab.ui.control.NumericEditField
        RectangleHeightLabel            matlab.ui.control.Label
        MotionRectHeightEditField       matlab.ui.control.NumericEditField
        ofscreenLabel                   matlab.ui.control.Label
        ofscreenLabel_2                 matlab.ui.control.Label
        ColorDropDownLabel              matlab.ui.control.Label
        MotionColorDropDown             matlab.ui.control.DropDown
        RewardStimuliTab                matlab.ui.container.Tab
        RewardLocationmmEditFieldLabel  matlab.ui.control.Label
        RewardLocationmmEditField       matlab.ui.control.NumericEditField
        TotalRectanglesWidthLabel_3     matlab.ui.control.Label
        RewardTotalRectWidEditField     matlab.ui.control.NumericEditField
        RectangleHeightLabel_2          matlab.ui.control.Label
        RewardHeightEditField           matlab.ui.control.NumericEditField
        ColorDropDown_2Label            matlab.ui.control.Label
        RewardColorDropDown             matlab.ui.control.DropDown
        ofscreenLabel_3                 matlab.ui.control.Label
        ofscreenLabel_4                 matlab.ui.control.Label
        RewardDurationLabel             matlab.ui.control.Label
        RewardDurationEditField         matlab.ui.control.NumericEditField
        RewardUnitDropDown              matlab.ui.control.DropDown
        NumberofRectanglesLabel_3       matlab.ui.control.Label
        MotionNumRectsEditField_2       matlab.ui.control.NumericEditField
        ManualStimuliTab                matlab.ui.container.Tab
        TotalRectanglesWidthLabel_4     matlab.ui.control.Label
        ManualTotalRectWidEditField     matlab.ui.control.NumericEditField
        RectangleHeightLabel_3          matlab.ui.control.Label
        ManualRectWidEditField          matlab.ui.control.NumericEditField
        ColorDropDown_3Label            matlab.ui.control.Label
        ManualColorDropDown             matlab.ui.control.DropDown
        NumberofRectanglesLabel_2       matlab.ui.control.Label
        ManualNumRectsEditField         matlab.ui.control.NumericEditField
        CueDurationifflashedLabel       matlab.ui.control.Label
        ManualCueDurationEditField      matlab.ui.control.NumericEditField
        ofscreenLabel_5                 matlab.ui.control.Label
        ofscreenLabel_6                 matlab.ui.control.Label
        ManualUnitDropDown              matlab.ui.control.DropDown
        NOTYETFUNCTIONALLabel           matlab.ui.control.Label
        RewardStimuliCheckBox           matlab.ui.control.CheckBox
        SessionSwitchLabel              matlab.ui.control.Label
        SessionSwitch                   matlab.ui.control.Switch
        COMEditFieldLabel               matlab.ui.control.Label
        COMEditField                    matlab.ui.control.EditField
        MotionCueCheckBox               matlab.ui.control.CheckBox
        CueSpeedEditFieldLabel          matlab.ui.control.Label
        CueSpeedEditField               matlab.ui.control.NumericEditField
        DisplayCuesSwitchLabel          matlab.ui.control.Label
        DisplayCuesSwitch               matlab.ui.control.ToggleSwitch
        FlashManualCueButton            matlab.ui.control.Button
        ShowManualCuefor1screenButton   matlab.ui.control.Button
        LapDistancecmEditFieldLabel     matlab.ui.control.Label
        LapDistancecmEditField          matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)        
       
        % parameters from motion cue tab
        MotionNumRects = 0; % number of rectangles in motion
        MotionWidthPercent = 0; % percent of screen taken up by motion rectangle width
        MotionSpacePercent = 0; % percent of width taken up by dark space
        MotionHeightPercent = 0; % percent of screen taken up by motion rectangle height
        MotionColor = 'White'; % color
        
        MotionRectWidth = 0; % width of the rectangles in motion
        MotionRectHeight = 0; % height of the rectangles in motion
        MotionSpaceWidth = 0; % width of the space


        % parameters from Reward Stimuli tab
        RewardNumRects = 0; % number of rectangles for reward stimuli
        RewardLocation = 0; % mm location to show reward stimuli
        RewardWidthPercent = 0;  % percent of screen taken up by reward rectangle width
        RewardHeightPercent = 0; % percent of screen taken up by reward rectangle height
        RewardDuration = 0; % length of reward duration in RewardDurationUnit
        RewardDurationUnit = 'mm'; % 'mm' or 'cm'
        RewardColor = 'Blue';
        
        % parameters from Manual Stimuli tab
        ManualNumRects = 0; % number of manually generated rectangles
        ManualWidthPercent = 0; % percent of screen taken up by manual rectangle width
        ManualHeightPercent = 0; % percent of screen taken up by manual rectangle height
        ManualCueDuration = 0; % length of reward duration in RewardDurationUnit
        ManualDurationUnit = 'mm'; % 'mm' or 'cm'
        ManualColor = 'Blue';
        
        
        % parameters from the check box panel
        isMotionCue; % motion cue checkbox
        isRewardStimuli; % reward stimuli checkbox
        lapDistance = 180; %mm
        rectVelo = 1; % cue speed entry field
        SerialID
        isStartOn = 'Clear'; % sessino switch value
        comNum = 5;
        displayValue = 'Off'; % display value switch, whether to display cues


    end
    
    methods (Access = private)
        
        function rgbArray = str2rgb(app, colorStr)
            %%% Given a color string, converts to rgb array
            switch colorStr
                case 'White'
                    rgbArray = [1, 1, 1];
                case 'Blue'
                    rgbArray = [0, 0, 1];
                case 'Grey'
                    rgbArray = [0.6, 0.6, 0.6];
                case 'Red'
                    rgbArray = [1, 0, 0];
                case 'Green'
                    rgbArray = [0, 1, 0];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SessionSwitch
        function SessionSwitchValueChanged(app, event)
            app.isStartOn  = app.SessionSwitch.Value;
            comStr = sprintf("COM%g", app.comNum);
            switch app.isStartOn 
                case 'On'
                    coms = instrfind;
                    if ~isempty(coms) 
                        fclose(coms);
                        delete(coms);
                    end
                    
                    app.SerialID = serial(comStr,'BaudRate',9600);
                    fopen(app.SerialID);
%                     clearstr = '<dp0,0.00,000,000!t0,0.00,000,000,00.000,00.000!^0,0.00,000,000,000!g0,0,0,0,0!>';
%                     for char = clearstr
%                         fwrite(app.SerialID, char)
%                     end
                case 'Clear'
                    fclose(app.SerialID);
                    delete(app.SerialID);
                    
            end
        end

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)
            app.comNum = str2num(app.COMEditField.Value);
      
        end

        % Value changed function: DisplayCuesSwitch
        function DisplayCuesSwitchValueChanged(app, event)
            app.displayValue = app.DisplayCuesSwitch.Value;


                    switch app.displayValue
                        case 'On'
                            switch app.isStartOn 
                                case 'On'
                                % Clear the workspace and the screen 
                                sca;
                               
                                % Call Default settings for settting up Psychtoolbox
                                PsychDefaultSetup(2);
                                
                                % Get the screen numbers
                                screens = Screen('Screens');
                                
                                % Draw to the external screen if avaliable
                                screenNumber = max(screens);
                                
                                % Define black and white
                                white = WhiteIndex(screenNumber);
                                black = BlackIndex(screenNumber);
                                
                                % Open an on screen window
                                [window, windowRect] = PsychImaging('OpenWindow', screenNumber, black);
                                windowWidth = windowRect(3);
                                windowHeight = windowRect(4);
                                
                                % Get the size of the on screen window
                                [screenXpixels, screenYpixels] = Screen('WindowSize', window);
                                
                                % Query the frame duration
                                ifi = Screen('GetFlipInterval', window);
                                
                                % Get the centre coordinate of the window
                                [xCenter, yCenter] = RectCenter(windowRect);
                                
                                % Set the color of the rect to white
                                time = 0;
                                
                                % Sync us and get a time stamp
                                vbl = Screen('Flip', window);
                                waitframes = 1;
                                
                                % Maximum priority level
                                topPriorityLevel = MaxPriority(window);
                                Priority(topPriorityLevel);
                                
                                % scale the width and height of the fill rectangles
                                motionRectWidth = (windowWidth/app.MotionNumRects) * app.MotionWidthPercent/100;
                                motionRectHeight = windowHeight * app.MotionHeightPercent/100;
                                
                                % get the width between rectangles
                                motionSpaceWidth = (windowWidth/app.MotionNumRects) * (1-app.MotionWidthPercent/100);
                                rewardSpaceWidth = (windowWidth/app.RewardNumRects) * (1-app.RewardWidthPercent/100);
                                
                                % scale the width and height of the reward rectangle;
                                rewardWidth = (windowWidth/app.RewardNumRects) * app.RewardWidthPercent/100;
                                rewardHeight = windowHeight * app.RewardHeightPercent/100;
                                
                                % set base rectangles
                                
                                if app.MotionNumRects > 1
                                    motionBaseRect = [0, 0, motionRectWidth, motionRectHeight]';
                                else
                                    motionBaseRect = [0, 0, motionRectWidth, motionRectHeight];
                                end
                                
                                if app.RewardNumRects > 1
                                    rewardBaseRect = [0, 0, rewardWidth, rewardHeight]';
                                else
                                    rewardBaseRect = [0, 0, rewardWidth, rewardHeight];
                                end


                                % flush the serial
                                flushinput(app.SerialID);

                                motionXpos = NaN(app.MotionNumRects,1);
                                rewardXpos = NaN(app.RewardNumRects,1);
                                manualXpos = NaN(app.ManualNumRects,1);
                                
                                motionYCenterMat = repmat(yCenter, app.MotionNumRects, 1);
                                rewardYCenterMat = repmat(yCenter, app.RewardNumRects, 1);
                                manualYCenterMat = repmat(yCenter, app.ManualNumRects, 1);
                                
                                % initial paramaters for timed reward stimuli
                                lastPosition = inf;
                                t_rew0 = -inf;
                                
                                % generates visual stimuli until a keyboard click
                                while ~KbCheck
                                    currentPosition = fscanf(app.SerialID,'%g',4);
                                    
                                    
                                    fprintf("Current Position: %g\n", currentPosition)
                                    % Position of the square on this frame
                                    xpos = windowWidth - currentPosition/app.lapDistance * windowWidth;
                                    % Add this position to the screen center coordinate. This is the point
                                    % we want our square to oscillate around
                                    for ii = 1:app.MotionNumRects
                                        motionXpos(ii) = app.rectVelo*xpos + (ii-1)*motionSpaceWidth;
                                    end
                                    
                                    % Center the rectangle on the centre of the screen
                                    centeredRect = CenterRectOnPointd(motionBaseRect, motionXpos, motionYCenterMat);
                                                                       
                                    % Draw the rect to the screen
                                    
                                    
                                    if app.isMotionCue
                                        
                                        % ensure wrap around
                                        if app.MotionNumRects > 1
                                            centeredRect(1,:)  = mod(centeredRect(1,:), windowWidth);
                                            centeredRect(3,:) = centeredRect(1,:) + motionRectWidth;
                                        else
                                            centeredRect(1)  = mod(centeredRect(1), windowWidth);
                                            centeredRect(3) = centeredRect(1) + motionRectWidth;
                                            centeredRect = centeredRect'; 
                                            
                                        end
                                        
                                        Screen('FillRect', window, app.str2rgb(app.MotionColor), centeredRect);
                                    end
                                    
                                    % draw the reward stimuli once
                                    
                                    if app.isRewardStimuli
                                        
                                        if and(currentPosition >= app.RewardLocation, lastPosition <=app.RewardLocation)
                                            t_rew0 = tic;
                                            fprintf("Location hit reward zone! time=%g \n", t_rew0)
                                        end

                                        if currentPosition>= app.RewardLocation
                                            fprintf("Reward Stimuli \n")
                                            switch app.RewardDurationUnit
                                                case 'mm'
                                                    if currentPosition <= (app.RewardLocation + app.RewardDuration)
                                                        
                                                        % calculate position of reward
                                                        xposReward =  windowWidth - (currentPosition-app.RewardLocation)/app.lapDistance * windowWidth;
                                                        fprintf("reward! %g", currentPosition)
                                                        for ii = 1:app.RewardNumRects
                                                           rewardXpos(ii) = app.rectVelo*xposReward + (ii-1)*rewardSpaceWidth;
                                                        end
                                                                                          
                                                        centeredRectReward = CenterRectOnPointd(rewardBaseRect, rewardXpos, rewardYCenterMat);
                                                                                                                
                                                        % ensure wrap around
                                    
                                                        if app.RewardNumRects > 1
                                                            centeredRectReward(1,:)  = mod(centeredRectReward(1,:), windowWidth);
                                                            centeredRectReward(3,:) = centeredRectReward(1,:) + rewardWidth;
                                                        else
                                                            centeredRectReward(1)  = mod(centeredRectReward(1), windowWidth);
                                                            centeredRectReward(3) = centeredRectReward(1) + rewardWidth;
                                                            centeredRectReward = centeredRectReward'; 
                                                            
                                                        end
                                                        Screen('FillRect', window, app.str2rgb(app.RewardColor), centeredRectReward);
                                                        
                                                    end
                                                case 'ms'
                                                    t_rew1 = tic; % keeps .1 microseconds so 10e7=1sec
                                                    fprintf("Show cue? time since start = %g\nDuration=%g\n", t_rew1-t_rew0, app.RewardDuration*1000)
                                                    if (t_rew1-t_rew0) <= app.RewardDuration * 10000
                                                        
                                                        xposReward =  windowWidth - (currentPosition-app.RewardLocation)/app.lapDistance * windowWidth;
                                                         fprintf("reward! %g", currentPosition)
                                                        for ii = 1:app.RewardNumRects
                                                            rewardXpos(ii) = app.rectVelo*xposReward + (ii-1)*rewardSpaceWidth;
                                                        end
                                                        centeredRectReward = CenterRectOnPointd(rewardBaseRect, rewardXpos, rewardYCenterMat);
                                                                                                                                                                          % ensure wrap around
                                                        % ensure wrap around 
                                                        if app.RewardNumRects > 1
                                                            centeredRectReward(1,:)  = mod(centeredRectReward(1,:), windowWidth);
                                                            centeredRectReward(3,:) = centeredRectReward(1,:) + rewardWidth;
                                                        else
                                                            centeredRectReward(1)  = mod(centeredRectReward(1), windowWidth);
                                                            centeredRectReward(3) = centeredRectReward(1) + rewardWidth;
                                                            centeredRectReward = centeredRectReward'; 
                                                            
                                                        end
                                                        
                                                        Screen('FillRect', window, app.str2rgb(app.RewardColor), centeredRectReward);
                                                        
                                                    end
                                                                                   
                                            end
                                        end
                                    end
            
                                    % Flip to the screen
                                    vbl  = Screen('Flip', window, vbl + (waitframes - 0.5) * ifi);
                                
                                    % Increment the time
                                    time = time + ifi;
                                    flushinput(app.SerialID); % flush serial input after each read
                                    
                                    lastPosition = currentPosition;
                                end
                                sca;              
                            case 'Clear'
                                warning("Must turn on Serial session to begin display")
                        end
                case 'Off'
                    sca;
            end
        end

        % Value changed function: MotionNumRectsEditField
        function MotionNumRectsEditFieldValueChanged(app, event)
            app.MotionNumRects = app.MotionNumRectsEditField.Value;
            
        end

        % Value changed function: MotionRectWidEditField
        function MotionRectWidEditFieldValueChanged(app, event)
            app.MotionWidthPercent = app.MotionRectWidEditField.Value;
            
        end

        % Value changed function: MotionRectHeightEditField
        function MotionRectHeightEditFieldValueChanged(app, event)
            app.MotionHeightPercent = app.MotionRectHeightEditField.Value;
            
        end

        % Value changed function: MotionColorDropDown
        function MotionColorDropDownValueChanged(app, event)
            app.MotionColor = app.MotionColorDropDown.Value;
            
        end

        % Value changed function: RewardLocationmmEditField
        function RewardLocationmmEditFieldValueChanged(app, event)
            app.RewardLocation = app.RewardLocationmmEditField.Value;
            
        end

        % Value changed function: RewardTotalRectWidEditField
        function RewardTotalRectWidEditFieldValueChanged(app, event)
            app.RewardWidthPercent = app.RewardTotalRectWidEditField.Value;
            
        end

        % Value changed function: RewardHeightEditField
        function RewardHeightEditFieldValueChanged(app, event)
            app.RewardHeightPercent = app.RewardHeightEditField.Value;
            
        end

        % Value changed function: RewardDurationEditField
        function RewardDurationEditFieldValueChanged(app, event)
            app.RewardDuration = app.RewardDurationEditField.Value;
         
        end

        % Value changed function: RewardUnitDropDown
        function RewardUnitDropDownValueChanged(app, event)
            app.RewardDurationUnit = app.RewardUnitDropDown.Value;
            
        end

        % Value changed function: RewardColorDropDown
        function RewardColorDropDownValueChanged(app, event)
            app.RewardColor = app.RewardColorDropDown.Value;
            
        end

        % Value changed function: ManualNumRectsEditField
        function ManualNumRectsEditFieldValueChanged(app, event)
            app.ManualNumRects = app.ManualNumRectsEditField.Value;
            
        end

        % Value changed function: ManualTotalRectWidEditField
        function ManualTotalRectWidEditFieldValueChanged(app, event)
            app.ManualWidthPercent = app.ManualTotalRectWidEditField.Value;
            
        end

        % Value changed function: ManualRectWidEditField
        function ManualRectWidEditFieldValueChanged(app, event)
            app.ManualHeightPercent = app.ManualRectWidEditField.Value;
            
        end

        % Value changed function: ManualCueDurationEditField
        function ManualCueDurationEditFieldValueChanged(app, event)
            app.ManualCueDuration = app.ManualCueDurationEditField.Value;
            
        end

        % Value changed function: ManualUnitDropDown
        function ManualUnitDropDownValueChanged(app, event)
            app.ManualDurationUnit = app.ManualUnitDropDown.Value;
            
        end

        % Value changed function: ManualColorDropDown
        function ManualColorDropDownValueChanged(app, event)
            app.ManualColor = app.ManualColorDropDown.Value;
            
        end

        % Value changed function: MotionCueCheckBox
        function MotionCueCheckBoxValueChanged(app, event)
            app.isMotionCue = app.MotionCueCheckBox.Value;
            
        end

        % Value changed function: RewardStimuliCheckBox
        function RewardStimuliCheckBoxValueChanged(app, event)
            app.isRewardStimuli = app.RewardStimuliCheckBox.Value;
            
        end

        % Value changed function: LapDistancecmEditField
        function LapDistancecmEditFieldValueChanged(app, event)
            app.lapDistance = app.LapDistancecmEditField.Value * 10; % convert to mm
            
        end

        % Value changed function: CueSpeedEditField
        function CueSpeedEditFieldValueChanged(app, event)
            app.rectVelo = app.CueSpeedEditField.Value;
            
        end

        % Value changed function: MotionNumRectsEditField_2
        function MotionNumRectsEditField_2ValueChanged(app, event)
            app.RewardNumRects = app.MotionNumRectsEditField_2.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {489, 489};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {405, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.MotionCueTab = uitab(app.TabGroup);
            app.MotionCueTab.Title = 'Motion Cue';
            app.NumberofRectanglesLabel = uilabel(app.MotionCueTab);
            app.NumberofRectanglesLabel.Text = 'Number of Rectangles';
            app.MotionNumRectsEditField = uieditfield(app.MotionCueTab, 'numeric');
            app.MotionNumRectsEditField.Limits = [0 170];
            app.MotionNumRectsEditField.RoundFractionalValues = 'on';
            app.MotionNumRectsEditField.ValueDisplayFormat = '%3.0f';
            app.MotionNumRectsEditField.ValueChangedFcn = createCallbackFcn(app, @MotionNumRectsEditFieldValueChanged, true);
            app.TotalRectanglesWidthLabel = uilabel(app.MotionCueTab);
            app.TotalRectanglesWidthLabel.Text = 'Total Rectangles'' Width';
            app.MotionRectWidEditField = uieditfield(app.MotionCueTab, 'numeric');
            app.MotionRectWidEditField.Limits = [0 100];
            app.MotionRectWidEditField.RoundFractionalValues = 'on';
            app.MotionRectWidEditField.ValueDisplayFormat = '%3.0f';
            app.MotionRectWidEditField.ValueChangedFcn = createCallbackFcn(app, @MotionRectWidEditFieldValueChanged, true);
            app.RectangleHeightLabel = uilabel(app.MotionCueTab);
            app.RectangleHeightLabel.Text = 'Rectangle Height';
            app.MotionRectHeightEditField = uieditfield(app.MotionCueTab, 'numeric');
            app.MotionRectHeightEditField.Limits = [0 100];
            app.MotionRectHeightEditField.RoundFractionalValues = 'on';
            app.MotionRectHeightEditField.ValueDisplayFormat = '%3.0f';
            app.MotionRectHeightEditField.ValueChangedFcn = createCallbackFcn(app, @MotionRectHeightEditFieldValueChanged, true);
            app.ofscreenLabel = uilabel(app.MotionCueTab);
            app.ofscreenLabel.Text = '% of screen';
            app.ofscreenLabel_2 = uilabel(app.MotionCueTab);
            app.ofscreenLabel_2.Text = '% of screen';
            app.ColorDropDownLabel = uilabel(app.MotionCueTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.MotionColorDropDown = uidropdown(app.MotionCueTab);
            app.MotionColorDropDown.Items = {'White', 'Grey', 'Blue', 'Green', 'Red'};
            app.MotionColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MotionColorDropDownValueChanged, true);
            app.MotionColorDropDown.Value = 'White';
            app.RewardStimuliTab = uitab(app.TabGroup);
            app.RewardStimuliTab.Title = 'Reward Stimuli';
            app.RewardLocationmmEditFieldLabel = uilabel(app.RewardStimuliTab);
            app.RewardLocationmmEditFieldLabel.Text = 'Reward Location (mm)';
            app.RewardLocationmmEditField = uieditfield(app.RewardStimuliTab, 'numeric');
            app.RewardLocationmmEditField.Limits = [0 4000];
            app.RewardLocationmmEditField.ValueDisplayFormat = '%3.0f';
            app.RewardLocationmmEditField.ValueChangedFcn = createCallbackFcn(app, @RewardLocationmmEditFieldValueChanged, true);
            app.TotalRectanglesWidthLabel_3 = uilabel(app.RewardStimuliTab);
            app.TotalRectanglesWidthLabel_3.Text = 'Total Rectangles'' Width';
            app.RewardTotalRectWidEditField = uieditfield(app.RewardStimuliTab, 'numeric');
            app.RewardTotalRectWidEditField.Limits = [0 100];
            app.RewardTotalRectWidEditField.RoundFractionalValues = 'on';
            app.RewardTotalRectWidEditField.ValueDisplayFormat = '%3.0f';
            app.RewardTotalRectWidEditField.ValueChangedFcn = createCallbackFcn(app, @RewardTotalRectWidEditFieldValueChanged, true);
            app.RectangleHeightLabel_2 = uilabel(app.RewardStimuliTab);
            app.RectangleHeightLabel_2.Text = 'Rectangle Height';
            app.RewardHeightEditField = uieditfield(app.RewardStimuliTab, 'numeric');
            app.RewardHeightEditField.Limits = [0 100];
            app.RewardHeightEditField.RoundFractionalValues = 'on';
            app.RewardHeightEditField.ValueDisplayFormat = '%3.0f';
            app.RewardHeightEditField.ValueChangedFcn = createCallbackFcn(app, @RewardHeightEditFieldValueChanged, true);
            app.ColorDropDown_2Label = uilabel(app.RewardStimuliTab);
            app.ColorDropDown_2Label.Text = 'Color';
            app.RewardColorDropDown = uidropdown(app.RewardStimuliTab);
            app.RewardColorDropDown.Items = {'White', 'Grey', 'Blue', 'Green', 'Red'};
            app.RewardColorDropDown.ValueChangedFcn = createCallbackFcn(app, @RewardColorDropDownValueChanged, true);
            app.RewardColorDropDown.Value = 'Blue';
            app.ofscreenLabel_3 = uilabel(app.RewardStimuliTab);
            app.ofscreenLabel_3.Text = '% of screen';
            app.ofscreenLabel_4 = uilabel(app.RewardStimuliTab);
            app.ofscreenLabel_4.Text = '% of screen';
            app.RewardDurationLabel = uilabel(app.RewardStimuliTab);
            app.RewardDurationLabel.Text = 'RewardDuration';
            app.RewardDurationEditField = uieditfield(app.RewardStimuliTab, 'numeric');
            app.RewardDurationEditField.Limits = [0 99999];
            app.RewardDurationEditField.RoundFractionalValues = 'on';
            app.RewardDurationEditField.ValueDisplayFormat = '%5.0f';
            app.RewardDurationEditField.ValueChangedFcn = createCallbackFcn(app, @RewardDurationEditFieldValueChanged, true);
            app.RewardUnitDropDown = uidropdown(app.RewardStimuliTab);
            app.RewardUnitDropDown.Items = {'mm', 'ms'};
            app.RewardUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @RewardUnitDropDownValueChanged, true);
            app.RewardUnitDropDown.Value = 'mm';
            app.NumberofRectanglesLabel_3 = uilabel(app.RewardStimuliTab);
            app.NumberofRectanglesLabel_3.Text = 'Number of Rectangles';
            app.MotionNumRectsEditField_2 = uieditfield(app.RewardStimuliTab, 'numeric');
            app.MotionNumRectsEditField_2.Limits = [0 170];
            app.MotionNumRectsEditField_2.RoundFractionalValues = 'on';
            app.MotionNumRectsEditField_2.ValueDisplayFormat = '%3.0f';
            app.MotionNumRectsEditField_2.ValueChangedFcn = createCallbackFcn(app, @MotionNumRectsEditField_2ValueChanged, true);
            app.ManualStimuliTab = uitab(app.TabGroup);
            app.ManualStimuliTab.Title = 'Manual Stimuli';
            app.TotalRectanglesWidthLabel_4 = uilabel(app.ManualStimuliTab);
            app.TotalRectanglesWidthLabel_4.Text = 'Total Rectangles'' Width';
            app.ManualTotalRectWidEditField = uieditfield(app.ManualStimuliTab, 'numeric');
            app.ManualTotalRectWidEditField.Limits = [0 100];
            app.ManualTotalRectWidEditField.RoundFractionalValues = 'on';
            app.ManualTotalRectWidEditField.ValueDisplayFormat = '%3.0f';
            app.ManualTotalRectWidEditField.ValueChangedFcn = createCallbackFcn(app, @ManualTotalRectWidEditFieldValueChanged, true);
            app.RectangleHeightLabel_3 = uilabel(app.ManualStimuliTab);
            app.RectangleHeightLabel_3.Text = 'Rectangle Height';
            app.ManualRectWidEditField = uieditfield(app.ManualStimuliTab, 'numeric');
            app.ManualRectWidEditField.Limits = [0 100];
            app.ManualRectWidEditField.RoundFractionalValues = 'on';
            app.ManualRectWidEditField.ValueDisplayFormat = '%3.0f';
            app.ManualRectWidEditField.ValueChangedFcn = createCallbackFcn(app, @ManualRectWidEditFieldValueChanged, true);
            app.ColorDropDown_3Label = uilabel(app.ManualStimuliTab);
            app.ColorDropDown_3Label.Text = 'Color';
            app.ManualColorDropDown = uidropdown(app.ManualStimuliTab);
            app.ManualColorDropDown.Items = {'White', 'Grey', 'Blue', 'Green', 'Red'};
            app.ManualColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ManualColorDropDownValueChanged, true);
            app.ManualColorDropDown.Value = 'Blue';
            app.NumberofRectanglesLabel_2 = uilabel(app.ManualStimuliTab);
            app.NumberofRectanglesLabel_2.Text = 'Number of Rectangles';
            app.ManualNumRectsEditField = uieditfield(app.ManualStimuliTab, 'numeric');
            app.ManualNumRectsEditField.Limits = [0 170];
            app.ManualNumRectsEditField.RoundFractionalValues = 'on';
            app.ManualNumRectsEditField.ValueDisplayFormat = '%3.0f';
            app.ManualNumRectsEditField.ValueChangedFcn = createCallbackFcn(app, @ManualNumRectsEditFieldValueChanged, true);
            app.CueDurationifflashedLabel = uilabel(app.ManualStimuliTab);
            app.CueDurationifflashedLabel.Text = 'Cue Duration if flashed';
            app.ManualCueDurationEditField = uieditfield(app.ManualStimuliTab, 'numeric');
            app.ManualCueDurationEditField.Limits = [0 9999];
            app.ManualCueDurationEditField.RoundFractionalValues = 'on';
            app.ManualCueDurationEditField.ValueDisplayFormat = '%4.0f';
            app.ManualCueDurationEditField.ValueChangedFcn = createCallbackFcn(app, @ManualCueDurationEditFieldValueChanged, true);
            app.ofscreenLabel_5 = uilabel(app.ManualStimuliTab);
            app.ofscreenLabel_5.Text = '% of screen';
            app.ofscreenLabel_6 = uilabel(app.ManualStimuliTab);
            app.ofscreenLabel_6.Text = '% of screen';
            app.ManualUnitDropDown = uidropdown(app.ManualStimuliTab);
            app.ManualUnitDropDown.Items = {'mm', 'ms'};
            app.ManualUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @ManualUnitDropDownValueChanged, true);
            app.ManualUnitDropDown.Value = 'mm';
            app.NOTYETFUNCTIONALLabel = uilabel(app.ManualStimuliTab);
            app.NOTYETFUNCTIONALLabel.Text = 'NOT YET FUNCTIONAL';
            app.RewardStimuliCheckBox = uicheckbox(app.LeftPanel);
            app.RewardStimuliCheckBox.ValueChangedFcn = createCallbackFcn(app, @RewardStimuliCheckBoxValueChanged, true);
            app.RewardStimuliCheckBox.Text = 'Reward Stimuli';
            app.SessionSwitchLabel = uilabel(app.LeftPanel);
            app.SessionSwitchLabel.Text = 'Session';
            app.SessionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.SessionSwitch.Items = {'Clear', 'On'};
            app.SessionSwitch.ValueChangedFcn = createCallbackFcn(app, @SessionSwitchValueChanged, true);
            app.SessionSwitch.Value = 'Clear';
            app.COMEditFieldLabel = uilabel(app.LeftPanel);
            app.COMEditFieldLabel.Text = 'COM #';
            app.COMEditField = uieditfield(app.LeftPanel, 'text');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = '5';
            app.MotionCueCheckBox = uicheckbox(app.LeftPanel);
            app.MotionCueCheckBox.ValueChangedFcn = createCallbackFcn(app, @MotionCueCheckBoxValueChanged, true);
            app.MotionCueCheckBox.Text = 'Motion Cue';
            app.CueSpeedEditFieldLabel = uilabel(app.LeftPanel);
            app.CueSpeedEditFieldLabel.Text = 'Cue Speed';
            app.CueSpeedEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CueSpeedEditField.Limits = [0 100];
            app.CueSpeedEditField.ValueDisplayFormat = '%g';
            app.CueSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @CueSpeedEditFieldValueChanged, true);
            app.CueSpeedEditField.Value = 1;
            app.DisplayCuesSwitchLabel = uilabel(app.LeftPanel);
            app.DisplayCuesSwitchLabel.Text = 'Display Cues';
            app.DisplayCuesSwitch = uiswitch(app.LeftPanel, 'toggle');
            app.DisplayCuesSwitch.ValueChangedFcn = createCallbackFcn(app, @DisplayCuesSwitchValueChanged, true);
            app.FlashManualCueButton = uibutton(app.LeftPanel, 'push');
            app.FlashManualCueButton.Text = 'Flash Manual Cue';
            app.ShowManualCuefor1screenButton = uibutton(app.LeftPanel, 'push');
            app.ShowManualCuefor1screenButton.Text = 'Show Manual Cue for 1 screen';
            app.LapDistancecmEditFieldLabel = uilabel(app.LeftPanel);
            app.LapDistancecmEditFieldLabel.Text = 'Lap Distance (cm)';
            app.LapDistancecmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LapDistancecmEditField.Limits = [0 999];
            app.LapDistancecmEditField.ValueDisplayFormat = '%g';
            app.LapDistancecmEditField.ValueChangedFcn = createCallbackFcn(app, @LapDistancecmEditFieldValueChanged, true);
            app.LapDistancecmEditField.Value = 180;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PeripheralStimuliControl_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end